classdef AnnealingGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TurboControlPanel             matlab.ui.container.Panel
        ConnecttoTurboButton          matlab.ui.control.Button
        DigitalInterlockPanel         matlab.ui.container.Panel
        TurboPumpLamp                 matlab.ui.control.Lamp
        TurboPumpLabel                matlab.ui.control.Label
        FurnaceLamp                   matlab.ui.control.Lamp
        FurnaceLampLabel              matlab.ui.control.Label
        MeasuredValuesPanel           matlab.ui.container.Panel
        TurboStatusLamp               matlab.ui.control.Lamp
        TurboStatusLabel              matlab.ui.control.Label
        TurboRPMEditField             matlab.ui.control.NumericEditField
        TurboRPMEditFieldLabel        matlab.ui.control.Label
        UITable                       matlab.ui.control.Table
        PlottingSettingsPanel         matlab.ui.container.Panel
        Switch                        matlab.ui.control.ToggleSwitch
        DisplaySettingsSwitch         matlab.ui.control.ToggleSwitch
        MasterControlsPanel           matlab.ui.container.Panel
        Lamp                          matlab.ui.control.Lamp
        ProgramStatusEditField        matlab.ui.control.EditField
        ProgramStatusEditFieldLabel   matlab.ui.control.Label
        StopButton                    matlab.ui.control.Button
        StartButton                   matlab.ui.control.Button
        FurnaceSyncingControlsPanel   matlab.ui.container.Panel
        SyncModeSwitch                matlab.ui.control.ToggleSwitch
        SyncModeSwitchLabel           matlab.ui.control.Label
        DegassingTempCEditField       matlab.ui.control.NumericEditField
        DegassingTempCEditFieldLabel  matlab.ui.control.Label
        NotSyncedLabel                matlab.ui.control.Label
        SyncStatusLamp                matlab.ui.control.Lamp
        SyncStatusLampLabel           matlab.ui.control.Label
        TripPointsPanel               matlab.ui.container.Panel
        FurnaceTempCEditField         matlab.ui.control.NumericEditField
        FurnaceTempCEditFieldLabel    matlab.ui.control.Label
        SystemLogTextArea             matlab.ui.control.TextArea
        SystemLogTextAreaLabel        matlab.ui.control.Label
        FurnaceDataPanel              matlab.ui.container.Panel
        RefreshFilesButton            matlab.ui.control.Button
        CheckFuranceFileButton        matlab.ui.control.Button
        FurnaceDataFileDropDown       matlab.ui.control.DropDown
        FurnaceDataFileDropDownLabel  matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        %%Data Display Variables
        %these are the current measured temp for the zones in celcius
        Z1ActTemp = 0;
        Z2ActTemp = 0;
        Z3ActTemp = 0;
        
        %this will contain the times in units of 24 hours collected from
        %the furnace. The first time will be set to 0
        FurnTime
        %ScaledFurnTime has the version of the the times in the units that
        %are being plotted. for example, it could contain the times as
        %dates, or in units of secs etc. This helps reduce rescaling data
        %each time.
        ScaledFurnTime
        
        %these contain all the previous zone temps in celcius
        Z1Temps
        Z2Temps
        Z3Temps
        
        %this contains the times collected from matlab for pres, mf, etc.
        %It is also in units of 24 hours, and will just be in matlab's
        %serial date number. The first point in this list is always defined
        %to be 0, and is the moment the furnace (and other uncontrolled devices) and matlab sync.
        Times
        ScaledTime
        
        %this is the matlab's serial date number for the moment that the
        %furnace and the matlab times are synced
        TZero
        
        %This is the time between the moment the furnace software's program 
        %began, and the sync point in units of 24 hours. Note, for manual
        %sync, FurnaceDelay is defined to be how much time has passed from
        %the start ofdata collecting to the time the start button was hit
        FurnaceDelay = 0;
        
        %this is the is the point (1,1) of the furnace data a. These are the times
        %that the first furnace temp recorded
        FurnStart
        
        %This is difference in days between matlab serial date number and
        %the itools serial date number. As far as I can tell, it seems to
        %be very close to exact this number in days.
        Matlab_iTools_TimeDif = 693960;
        
        %This is the a date string that is initated during start up that
        %can be used to name and distinguish log and pressure files.
        TimeStarted
        
        %This stores the info from the CustomTime app
        CustomControl

        %this tells the update_data function that it needs to rescale all
        %the data
        UScaleChanged = true;
        CScaleChanged %this is only here so the CustomControl doesnt throw errors
        
        
        %%Syncing variables
        %this is a boolean to know if the two times have been synced
        IsSynced = false;
        
        %this is the temperature tolerance of plus minus 5 degrees celcius.
        %This is exculsively used for automatic syncing of the furance.
        TempTol = 5
        
        %%Turbo syncing variable
        %this is the serial connection to the turbo pump
        TurboSerial
        
        %this is the device address for the turbo pump. It may be useful to
        %hard code this and prevent really long connecting wait times
        TurboAddress
    end
    
    methods (Access = private)
        
        function Update_Log(app,msg)
            %this function updates the system log, so the history of the
            %program is easily and conveniently displayed. It also saves
            %the log text in a data file for later viewing.
            if strcmp(app.SystemLogTextArea.Value,'')
                app.SystemLogTextArea.Value = [datestr(now),': ' msg];
            else
                app.SystemLogTextArea.Value = [app.SystemLogTextArea.Value; datestr(now),': ' msg];
                
            end
            pause(.1)%this pause lets the text box notice the change in value
            %so it scrolls all the way down
            scroll(app.SystemLogTextArea,'bottom')
            
            %uncomment next 3 lines for actual operation:
%             datafile = fopen(['Data Files/System Log Files/',app.TimeStarted,'.txt'],'a');
%             fprintf(datafile,'%s\n',app.SystemLogTextArea.Value{length(app.SystemLogTextArea.Value)});
%             fclose(datafile);
        end
        
        function ChangeInterlock(app,element)
            switch element
                %this function is how the GUI changes the interlock. This
                %function has the ability to make appropiate changes to the
                %interlock and then detects when the program is ready to
                %run.
                case 'furnace'
%                     readfilename = ['Data Files/Furnace Data Files/', app.FurnaceDataFileDropDown.Value];
%                     copiedfilename = ['Backup Files/Copyof',app.FurnaceDataFileDropDown.Value];
%                     copyfile(readfilename, copiedfilename)  
                    Update_Log(app,['Furnace will run ',app.FurnaceDataFileDropDown.Value])
                    app.CheckFuranceFileButton.BackgroundColor = [.94,.94,.94];%gray
                    app.FurnaceLamp.Color = 'g';
                case 'clear furnace'
                    app.CheckFuranceFileButton.BackgroundColor = [.39,.83,.07];%green
                    app.FurnaceLamp.Color = 'r';
                case 'turbo'
                    Update_Log(app,['Furnace will run ',app.FurnaceDataFileDropDown.Value])
                    app.ConnecttoTurboButton.BackgroundColor = [.94,.94,.94];%gray
                    app.TurboPumpLamp.Color = 'g';
                case 'clear turbo'
                    app.ConnecttoTurboButton.BackgroundColor = [.39,.83,.07];%green
                    app.TurboPumpLamp.Color = 'r';
            end
            if isequal(app.FurnaceLamp.Color, [0 1 0]) && isequal(app.TurboPumpLamp.Color, [0 1 0])
                Change_Status(app,'Ready')
            else
                app.Lamp.Color = [0.90,0.90,0.90];
                app.StartButton.BackgroundColor = [.94,.94,.94];
                app.StartButton.Enable = 'Off';
                app.ProgramStatusEditField.Value  = 'Off';
            end
        end
        
        function Change_Status(app,status)
            %this fuction performs all the actions required when the status
            %of the program changes
            switch status
                case 'Off'
                    app.Lamp.Color = [0.90,0.90,0.90];
                    app.SyncStatusLamp.Color = 'r';
                    app.NotSyncedLabel.Text = 'Not Synced';
                    app.SyncModeSwitch.Enable = 'on';
                    app.IsSynced = false;
                    app.TurboStatusLamp.Color = [.90 .90 .90];%gray
                    %clear plots
                    cla(app.UIAxes)
                    legend(app.UIAxes,'hide')
                    %reset buttons
                    app.StopButton.Enable = 'off';
                    app.StartButton.Enable = 'off';
                    app.StartButton.BackgroundColor = [.94,.94,.94];
                    app.FurnaceDataFileDropDown.Enable = 'on';
                    app.CheckFuranceFileButton.Enable = 'on';
                    app.CheckFuranceFileButton.BackgroundColor = [.39,.83,.07];
                    app.ConnecttoTurboButton.Enable = 'on';
                    app.ConnecttoTurboButton.BackgroundColor = [.39,.83,.07];
                    %reset interlock
                    app.FurnaceLamp.Color = 'r';
                    app.TurboPumpLamp.Color = 'r';
                    %reset time
                    app.Times = [];
                    app.UScaleChanged = true;
                    app.DisplaySettingsSwitch.Value = 'Elasped Time';
                    app.Switch.Value = 'All Time';
                    app.DisplaySettingsSwitch.Enable = 'off';
                    app.Switch.Enable = 'off';
                    try
                        delete(app.CustomControl)
                        %this is the only way to delete the remote. it
                        %cannot be manually closed
                    catch 
                    end
                case 'Active'
                    app.Lamp.Color = 'g';
                    app.StartButton.Enable = 'off';
                    app.CheckFuranceFileButton.Enable = 'off';
                    app.ConnecttoTurboButton.Enable = 'off';
                    app.FurnaceDataFileDropDown.Enable = 'off';
                    app.SyncModeSwitch.Enable = 'off';
                    app.StopButton.Enable = 'on';
                    app.DisplaySettingsSwitch.Enable = 'on';
                    app.Switch.Enable = 'on';
                case 'Ready'
                    app.Lamp.Color = 'c';
                    app.StartButton.BackgroundColor = [.39,.83,.07];%green
                    app.StartButton.Enable = 'On';
                otherwise
                    errordlg(['Cannot Recongize status: ',status],'Status Error');
            end
            if not(strcmp(status,app.ProgramStatusEditField.Value))
                Update_Log(app,['Status changed from ', app.ProgramStatusEditField.Value,' to ',status])
                app.ProgramStatusEditField.Value  = status;
            end            
        end
        
        function Update_Data(app)
            %this function is realy the meat of the whole GUI. It displays
            %and plots all the data in real time. 
            
            %These ifs will make the font red if a value is over trip point
            %for quicker visual recognition
            if app.Z1ActTemp < app.FurnaceTempCEditField.Value
                addStyle(app.UITable,uistyle('FontColor' , 'black'),'cell',[1,1]);
            else
                addStyle(app.UITable,uistyle('FontColor' , 'red'),'cell',[1,1]);
            end
            if app.Z2ActTemp < app.FurnaceTempCEditField.Value
                addStyle(app.UITable,uistyle('FontColor' , 'black'),'cell',[2,1]);
            else
                addStyle(app.UITable,uistyle('FontColor' , 'red'),'cell',[2,1]);
            end
            if app.Z3ActTemp < app.FurnaceTempCEditField.Value
                addStyle(app.UITable,uistyle('FontColor' , 'black'),'cell',[3,1]);
            else
                addStyle(app.UITable,uistyle('FontColor' , 'red'),'cell',[3,1]);
            end
            
            %% This section reads the uncontrolled data
            try
                furnaceData = readmatrix(strcat('Data Files/Furnace Data Files/', app.FurnaceDataFileDropDown.Value));
                furnaceData(:,1) = (furnaceData(:,1) - app.FurnStart);
                app.FurnTime = furnaceData(:,1);
                app.Z1Temps = furnaceData(:,2);
                app.Z2Temps = furnaceData(:,3);
                app.Z3Temps = furnaceData(:,4);
                app.Z1ActTemp = app.Z1Temps(end);
                app.Z2ActTemp = app.Z2Temps(end);
                app.Z3ActTemp = app.Z3Temps(end);
                app.UITable.Data = table([app.Z1ActTemp;app.Z2ActTemp;app.Z3ActTemp]);
            catch
                Update_Log(app,'Error reading furnace data file')
                Change_Status(app,'Off')
            end
            
            %now we graph the uncontrolled data
            if app.UScaleChanged
                app.UScaleChanged = false;% I set this right away to minimize the effects of
                %switching scale while Rescale_Data is running
                Rescale_UCData(app)%this rescales and plots all the points
            else
                Append_UCData(app)%this only adds points in proper format w/o changing old data
            end
            %this fucntion changes the light status and updates the RPM
            %value
            %Uncomment this next line when ready to test with turbo
%             CheckTruboStatus(app)
            
            %% now we collect the controlled data if synced
            if app.IsSynced
                app.Times = [app.Times;now-app.TZero];
            end
        end
        
        function Rescale_UCData(app)
%             try
                %all these nested ifs and swithces look intimidating, 
                %but I assure you that they are not that bad.
                %All that is happening is a custom time adjustment, or
                %an all time adjustment. This first part assumes
                %elapsed time is on. If it is set to actual time, the
                %correction is made below
                
                %added note, this is where you should add plotting of
                %things that are uncontrolled (not started by the program). 
                %For example the furnace and the bubbler heaters, which must 
                %be synced before the plotting of the controlled devices begins
                
                %this is really just a huge chuck of if and switches for
                %the sole purpose of being able to adjust the time
                %displayed. If it is too slow, or bad at garbage
                %collection, hardcoding may be the option. If so, this can
                %be used as a model
                if strcmp(app.Switch.Value, 'Custom Time')
                    switch app.CustomControl.DropDown.Value 
                        case 'min'
                            app.ScaledFurnTime = (app.FurnTime-app.FurnaceDelay)*1440;
                            app.UIAxes.XLabel.String = 'Time (min)';
                            if app.CustomControl.DisplayLastTime.Value
                                futLims = [app.ScaledFurnTime(end)-app.CustomControl.DisplayTimeEditField.Value,app.ScaledFurnTime(end)];
                            else
                                switch app.CustomControl.DropDown_2.Value
                                    case 'min'
                                        futLims = [app.CustomControl.StartingTimeSpinner.Value,app.CustomControl.StartingTimeSpinner.Value+app.CustomControl.DisplayTimeEditField.Value];
                                    case 'hr'
                                        futLims =[60*app.CustomControl.StartingTimeSpinner.Value,60*app.CustomControl.StartingTimeSpinner.Value+app.CustomControl.DisplayTimeEditField.Value];
                                    case 'sec'
                                        futLims =[app.CustomControl.StartingTimeSpinner.Value/60,app.CustomControl.StartingTimeSpinner.Value/60+app.CustomControl.DisplayTimeEditField.Value];
                                end
                            end
                        %notice, I save the XLims as futlims instead of
                        %immediately changing the UIaxes. This is because
                        %an error will be thrown when switching from
                        %elasped time to actual time, due to changing data
                        %types for the x axis
                        
                        %this is solved by plotting the data first, then
                        %scaling it
                        case 'hr'
                            app.ScaledFurnTime = (app.FurnTime-app.FurnaceDelay)*24;
                            app.UIAxes.XLabel.String = 'Time (hr)';
                            if app.CustomControl.DisplayLastTime.Value
                                futLims = [app.ScaledFurnTime(end)-app.CustomControl.DisplayTimeEditField.Value,app.ScaledFurnTime(end)];
                            else
                                switch app.CustomControl.DropDown_2.Value
                                    case 'hr'
                                        futLims =[app.CustomControl.StartingTimeSpinner.Value,app.CustomControl.StartingTimeSpinner.Value+app.CustomControl.DisplayTimeEditField.Value];
                                    case 'min'
                                        futLims =[app.CustomControl.StartingTimeSpinner.Value/60,app.CustomControl.StartingTimeSpinner.Value/60+app.CustomControl.DisplayTimeEditField.Value];
                                    case 'sec'
                                        futLims =[app.CustomControl.StartingTimeSpinner.Value/3600,app.CustomControl.StartingTimeSpinner.Value/3600+app.CustomControl.DisplayTimeEditField.Value];
                                        
                                end
                            end
                        
                        case 'sec'
                            app.ScaledFurnTime = (app.FurnTime-app.FurnaceDelay)*86400;
                            app.UIAxes.XLabel.String = 'Time (sec)';
                            if app.CustomControl.DisplayLastTime.Value
                                futLims = [app.ScaledFurnTime(end)-app.CustomControl.DisplayTimeEditField.Value,app.ScaledFurnTime(end)];
                            else
                                switch app.CustomControl.DropDown_2.Value
                                    case 'sec'
                                        futLims =[app.CustomControl.StartingTimeSpinner.Value,app.CustomControl.StartingTimeSpinner.Value+app.CustomControl.DisplayTimeEditField.Value];
                                    case'min'
                                        futLims =[60*app.CustomControl.StartingTimeSpinner.Value,60*app.CustomControl.StartingTimeSpinner.Value+app.CustomControl.DisplayTimeEditField.Value];
                                    case 'hr'
                                        futLims =[app.CustomControl.StartingTimeSpinner.Value*3600,app.CustomControl.StartingTimeSpinner.Value*3600+app.CustomControl.DisplayTimeEditField.Value];
                                end
                            end
                    end
                   
                    
                else %switch value is all time
                    %furnace plotting
                    if (app.FurnTime(end)-app.FurnTime(1))*1440 >240%minutes
                        app.ScaledFurnTime = (app.FurnTime-app.FurnaceDelay)*24;
                        app.UIAxes.XLabel.String = 'Time (hr)';
                    elseif (app.FurnTime(end)-app.FurnTime(1))*1440 < 1%minute
                        app.ScaledFurnTime = (app.FurnTime-app.FurnaceDelay)*86400;
                        app.UIAxes.XLabel.String = 'Time (sec)';
                    else
                        app.ScaledFurnTime = (app.FurnTime-app.FurnaceDelay)*1440;
                        app.UIAxes.XLabel.String = 'Time (min)';
                    end
                end
                %this is the end of the elasped time nested ifs
                
                %now for the actual time adjustment if needed
                if strcmp(app.DisplaySettingsSwitch.Value, 'Actual Time')
                    app.UIAxes.XLabel.String = 'Time';
                    %thefurn time will display the correct date that it was
                    %recorded. The bubbler heater however can only display
                    %the correct hour that it occurs, so it is very
                    %important to save the date/month in the file name, so
                    %the data aren't lost
                    app.ScaledFurnTime = datetime(app.FurnTime+(app.Matlab_iTools_TimeDif+app.FurnStart), 'ConvertFrom', 'datenum');
                    if strcmp(app.Switch.Value, 'Custom Time')
                        switch app.CustomControl.DropDown.Value 
                            case 'min'
                                futLims = [datetime(app.Matlab_iTools_TimeDif+app.FurnStart + futLims(1)/1440+app.FurnaceDelay, 'ConvertFrom', 'datenum'), datetime(app.Matlab_iTools_TimeDif+app.FurnStart + futLims(2)/1440+app.FurnaceDelay, 'ConvertFrom', 'datenum')];
                            case 'hr'
                                futLims = [datetime(app.Matlab_iTools_TimeDif+app.FurnStart + futLims(1)/24+app.FurnaceDelay,'ConvertFrom', 'datenum'), datetime(app.Matlab_iTools_TimeDif+app.FurnStart+ futLims(2)/24+app.FurnaceDelay, 'ConvertFrom', 'datenum')];
                            case 'sec'
                                futLims = [datetime(app.Matlab_iTools_TimeDif+app.FurnStart +futLims(1)/86400+app.FurnaceDelay, 'ConvertFrom', 'datenum'), datetime(app.Matlab_iTools_TimeDif+app.FurnStart + futLims(2)/86400+app.FurnaceDelay, 'ConvertFrom', 'datenum')];
                        end
                    end
                end
%             catch ME
%                 ME
%                 Update_Log(app,'Uncontrolled hardware rescaling error')
%             end
%             %boom, the rescaling is done, painless right? lol. now lets
%             %plot
%             try
                plot(app.UIAxes,app.ScaledFurnTime,app.Z1Temps);
                hold(app.UIAxes, 'on')
                plot(app.UIAxes,app.ScaledFurnTime,app.Z2Temps);
                plot(app.UIAxes,app.ScaledFurnTime,app.Z3Temps);
                hold(app.UIAxes, 'off')
                legend(app.UIAxes,'Zone 1','Zone 2','Zone 3','location','northwest')
               
                if strcmp(app.Switch.Value, 'Custom Time')
                    app.UIAxes.XLim = futLims;
                else
                    app.UIAxes.XLimMode = 'auto';
                end
                
%             catch ME
%                 ME
%                 Update_Log(app,'Uncontrolled Hardware Plotting Error')
%             end
            %now all the previous data is stored into app.Scaledxxxxxx.
            %This means all additional data can simplely be scaled and
            %added
        end
        
        function Append_UCData(app)
            %this is only append the data if new data is added (duh)
            if length(app.ScaledFurnTime) ~= length(app.FurnTime)
%                 try
                    if strcmp(app.Switch.Value, 'Custom Time')
                        switch app.CustomControl.DropDown.Value 
                            case 'min'
                                %If no data is added the appendedxxxxx will
                                %be empty, otherwise it will contain all
                                %the new points to scale
                                appendedFurnTime = (app.FurnTime(length(app.ScaledFurnTime)+1:end)-app.FurnaceDelay)*1440;
                                if app.CustomControl.DisplayLastTime.Value
                                    try
                                        futLims = [appendedFurnTime(end)-app.CustomControl.DisplayTimeEditField.Value,appendedFurnTime(end)];
                                    catch
                                        %this will catch when the furntime
                                        %does not increase in lenght
                                        futLims = app.UIAxes.XLim;
                                    end
                                else
                                    futLims = app.UIAxes.XLim;
                                end
                            
                            case 'hr'
                                appendedFurnTime = (app.FurnTime(length(app.ScaledFurnTime)+1:end)-app.FurnaceDelay)*24;
                                if app.CustomControl.DisplayLastTime.Value
                                    try
                                        futLims = [appendedFurnTime(end)-app.CustomControl.DisplayTimeEditField.Value,appendedFurnTime(end)];
                                    catch
                                        %this will catch when the furntime
                                        %does not increase in lenght
                                        futLims = app.UIAxes.XLim;
                                    end
                                else
                                    futLims = app.UIAxes.XLim;
                                end
                            
                            case 'sec'
                                appendedFurnTime = (app.FurnTime(length(app.ScaledFurnTime)+1:end)-app.FurnaceDelay)*86400;
                                if app.CustomControl.DisplayLastTime.Value
                                    try
                                        futLims = [appendedFurnTime(end)-app.CustomControl.DisplayTimeEditField.Value,appendedFurnTime(end)];
                                    catch
                                        %this will catch when the furntime
                                        %does not increase in lenght
                                        futLims = app.UIAxes.XLim;
                                    end
                                else
                                    futLims = app.UIAxes.XLim;
                                end
                        end
                       
                        
                    else %switch value is all time
                        %furnace plotting
                        if (app.FurnTime(end)-app.FurnTime(1))*1440 >240%minutes
                            appendedFurnTime = (app.FurnTime(length(app.ScaledFurnTime)+1:end)-app.FurnaceDelay)*24;
                        elseif (app.FurnTime(end)-app.FurnTime(1))*1440 < 1%minute
                            appendedFurnTime = (app.FurnTime(length(app.ScaledFurnTime)+1:end)-app.FurnaceDelay)*86400;
                        else
                            appendedFurnTime = (app.FurnTime(length(app.ScaledFurnTime)+1:end)-app.FurnaceDelay)*1440;
                        end
                    end
                    %this is the end of the elasped time nested ifs
                    
                    %now for the actual time adjustment if needed
                    if strcmp(app.DisplaySettingsSwitch.Value, 'Actual Time')
                        appendedFurnTime = datetime(app.FurnTime(length(app.ScaledFurnTime)+1:end)+(app.Matlab_iTools_TimeDif+app.FurnStart), 'ConvertFrom', 'datenum');
                        if strcmp(app.Switch.Value, 'Custom Time') && app.CustomControl.DisplayLastTime.Value
                            switch app.CustomControl.DropDown.Value 
                                case 'min'
                                    try
                                        futLims = [datetime(app.Matlab_iTools_TimeDif+app.FurnStart + futLims(1)/1440+app.FurnaceDelay, 'ConvertFrom', 'datenum'), datetime(app.Matlab_iTools_TimeDif+app.FurnStart + futLims(2)/1440+app.FurnaceDelay, 'ConvertFrom', 'datenum')];
                                    catch
                                        futLims = app.UIAxes.XLim;
                                        %this will prevent errors when the
                                        %appenedfurntime is empty
                                    end
                                case 'hr'
                                    try
                                        futLims = [datetime(app.Matlab_iTools_TimeDif+app.FurnStart + futLims(1)/24+app.FurnaceDelay,'ConvertFrom', 'datenum'), datetime(app.Matlab_iTools_TimeDif+app.FurnStart + futLims(2)/24+app.FurnaceDelay, 'ConvertFrom', 'datenum')];
                                    catch
                                        futLims = app.UIAxes.XLim;
                                    end
                                case 'sec'
                                    try
                                        futLims = [datetime(app.Matlab_iTools_TimeDif+app.FurnStart+futLims(1)/86400+app.FurnaceDelay, 'ConvertFrom', 'datenum'), datetime(app.Matlab_iTools_TimeDif+app.FurnStart+ futLims(2)/86400+app.FurnaceDelay, 'ConvertFrom', 'datenum')];
                                    catch
                                        futLims = app.UIAxes.XLim;
                                    end
                            end
                        end
                    end
                    %those if and switches are pretty much identical to
                    %rescaling version, with a few catches for when stuff
                    %is empty. Now we can add the new scaled points and
                    %keep plotting
                    app.ScaledFurnTime = [app.ScaledFurnTime;appendedFurnTime];
%                 catch
%                     Update_Log(app,'Furance appending error')
%                 end
%                 try
                    if strcmp(app.Switch.Value, 'Custom Time')
                        app.UIAxes.XLim = futLims;
                    end
                    plot(app.UIAxes,app.ScaledFurnTime,app.Z1Temps);
                    hold(app.UIAxes, 'on')
                    plot(app.UIAxes,app.ScaledFurnTime,app.Z2Temps);
                    plot(app.UIAxes,app.ScaledFurnTime,app.Z3Temps);
                    hold(app.UIAxes, 'off')
                    legend(app.UIAxes,'Zone 1','Zone 2','Zone 3','location','northwest')
%                 catch ME
%                     ME
%                     Update_Log(app,'Furnace Plotting Error')
%                 end
            end
            %hopefully this reduces the scaling time significantly.
            %Rescaling tens or hundreds of thousands of points each time
            %seems ridulous, so hopefully storing them in memory speeds up
            %the process.
        end
        
        function SyncTime(app)
            %this is entirely for the automatic furnace syncing
            syncerror = false;
            app.SyncStatusLamp.Color = [1.00,0.41,0.16];%orange
            app.NotSyncedLabel.Text = 'Waiting for Stablization';
            while not(app.IsSynced) && not(syncerror)
                %this is the loop that waits for syncing
                Update_Data(app)
                avgtemp = (app.Z1ActTemp+app.Z2ActTemp+app.Z3ActTemp)/3;
                if avgtemp >= app.DegassingTempCEditField.Value + app.TempTol && strcmp(app.ProgramStatusEditField.Value,'Active')
                    Update_Log(app,'Attempting to read ramp')
                    app.SyncStatusLamp.Color = 'y';
                    app.NotSyncedLabel.Text = 'Syncing';
                    synctimes = app.FurnTime(end);
                    synctemps = avgtemp;
                    Update_Data(app)
                    while 1440*(app.FurnTime(end)-synctimes(1)) < 5%minutes
                        synctimes = [synctimes,app.FurnTime(end)];
                        synctemps = [synctemps,avgtemp];
                        Update_Data(app)
                        matlabtime = now;
                        pause(1)
                    end
                    fit = polyfit(synctimes,synctemps,1);
                    slope = fit(1);
                    yint = fit(2);
                    itoolsguess = (app.DegassingTempCEditField.Value-yint)/slope;
                    %zeropoint = datestr(matlabtime -(synctimes(end)-itoolsguess))
                    app.TZero = matlabtime -(synctimes(end)-itoolsguess);%this is the 
                    % time that we wil treat as zero (in units of matlab time)
                    app.FurnaceDelay = itoolsguess - app.FurnTime(1);%this is the how long
                    %ago the furnace began recording in days
                    Update_Log(app,['Synced zeropoint at ',datestr(app.TZero)])
                    app.IsSynced = true;
                    app.UScaleChanged = true;
                    if stable
                        Update_Log(app,'Successfully Synced')
                    else
                        Update_Log(app,'Did not reach stablility. Sync may be inaccurate')
                    end
                    app.SyncStatusLamp.Color = 'g';
                    app.NotSyncedLabel.Text = 'Synced';
                
                elseif avgtemp <= app.DegassingTempCEditField.Value - app.TempTol
                    Update_Log(app,'Fell below Degassing Range. Recheck Parameters')
                    syncerror = true;
                end
                if length(app.Z1Temps) > 10
                    %this is how the furance determines stability. It is
                    %not required, but will update log with a warning if it
                    %is not stable. Note .1 is arbitary.
                    stable = true;
                    for i = length(app.Z1Temps)-10:length(app.Z1Temps)
                        if abs((app.Z1Temps(i)+app.Z2Temps(i)+app.Z3Temps(i))/3-app.DegassingTempCEditField.Value) > .1
                            stable = false;
                            break
                        end
                    end
                    %to prevent weird effects when exiting, I want to make
                    %sure it can only change the colors when the program is
                    %running
                    if stable && strcmp(app.ProgramStatusEditField.Value,'Active')
                        app.SyncStatusLamp.Color = 'y';
                        app.NotSyncedLabel.Text = 'Stable';
                    elseif strcmp(app.ProgramStatusEditField.Value,'Active')
                        app.SyncStatusLamp.Color = [1.00,0.41,0.16];%orange
                        app.NotSyncedLabel.Text = 'Waiting for Stablization';
                    end
                end
                pause(1)
            end
        end
        
        function CheckTurboStatus(app)
            app.TurboRPMEditField.Value = getRPM(app);
            if app.TurboRPMEditField.Value>= 1500
                app.TurboStatusLamp.Color = 'g';
            else
                app.TurboStatusLamp.Color = 'r';
            end
        end
        
        function actRPM = getRPM(app)
                parameter = '309';%this is the parameter for actual rpm in Hz
                codes = unicode2native([app.TurboAddress,'00',parameter,'02=?'], 'US-ASCII');
                %codes is the ASCII character values for all the letters in
                %this string.
                checksum = mod(sum(codes),256);
                %checksum is error correcting code that makes sure that the
                %there wasnt an error
                
                %I have never worked with error correcting code before. so
                %you may have to add some fucnitonality that repeats the
                %query if an error is found
                try
                    val = query(app.TurboSerial,[app.TurboAddress,'00',parameter,'02=?',checksum]);
                    %if this doesnt work try:
                    %query(app.TurboSerial,[app.TruboAddress,'00',parameter,'02=?',checksum],'%s','%c')
                    %query(app.TurboSerial,[app.TruboAddress,'00',parameter,'02=?',checksum],'%c','%s')
                    %or
                    %query(app.TurboSerial,[app.TruboAddress,'00',parameter,'02=?',checksum],'%s','%s')
                    %if that doesn't work try all four again, but adding
                    %the newline character '\n' to the end of each so e.g.
                    %query(app.TurboSerial,[app.TruboAddress,'00',parameter,'02=?',checksum],'%s\n','%s\n')
                    if isempty(RPM)
                        error('Failed to read')
                    end
                    RPM = str2double(val(11:16));
                    if RPM<0 || RPM > 999999
                        error('Invalid RPM value')
                    end
                    actRPM = RPM
                catch ME
%                     ME
                    actRPM = 'failed';
                end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.TimeStarted = strrep(datestr(now),':',';');%The colons are replaced
            %so I can use this string to name a .txt file
            try
                f = dir('Data Files/Furnace Data Files/');
                files = {};
                if length(f)<3
                    error('No furnace data files were found')
                end
                for i = 3:length(f)
                    file = f(i);
                    files(i-2) = {file.name};
                end
                app.FurnaceDataFileDropDown.Items = files;
            catch
                Update_Log(app,'No furnace data files were found')
            end
            Update_Log(app,'System Ready')
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            YN = uiconfirm(app.UIFigure,'Do you want to close the app?', 'Close request');
            if strcmpi(YN,'OK') && (strcmp(app.ProgramStatusEditField.Value,'Off') || strcmp(app.ProgramStatusEditField.Value,'Ready'))
                Change_Status(app,'Off')
                try
                    delete(app.CustomControl)
                catch 
                end
                delete(app)
                %here I make sure to turn the furnace off if the app is closed
            elseif strcmpi(YN,'OK')
                errordlg('Cannot exit program while program is active','Program Busy');
            end
        end

        % Button pushed function: CheckFuranceFileButton
        function CheckFuranceFileButtonPushed(app, event)
            try
                ChangeInterlock(app,'clear furnace')
                furnaceData = readmatrix(strcat('Data Files/Furnace Data Files/', app.FurnaceDataFileDropDown.Value));
                app.FurnStart = furnaceData(1,1);
                furnaceDatalast = (furnaceData(end,1) - app.FurnStart);
                if furnaceDatalast*1440 > 30
                    YN = uiconfirm(app.UIFigure,[app.FurnaceDataFileDropDown.Value,' contains ',int2str(furnaceDatalast*1440),' minutes of data. Overwrite?'], 'File Already contains data.');
                    if strcmpi(YN,'OK')
                        ChangeInterlock(app,'furnace')
                    end
                else
                    ChangeInterlock(app,'furnace')
                end
            catch ME
                ME
                if isempty(furnaceData)
                    Update_Log(app,'Furnace data file empty')
                else
                    Update_Log(app,'Error reading furnace data file')
                end
                Change_Status(app,'Off')
            end
        end

        % Button pushed function: ConnecttoTurboButton
        function ConnecttoTurboButtonPushed(app, event)
            try
                ChangeInterlock(app,'clear turbo')
                
                %this is my precode for auto connecting to the turbo pump:
                %notice the simularities of connecting to the buttrefly
                %valve in the cvd gui
                %uncomment this next section when you are ready to try it
                %connecting to the turbo pump
                
%                 ports = serialportlist;
%                 %if you know the COM port and want to speed up the process uncomment
%                 %the next line and change the COM value
% %                 ports = 'COMXX'
%                 comport = [];
%                 connected = false;
%                 warning('off')
%                 for i = 1:length(ports)
%                     app.TurboSerial = instrfind('Type', 'serial', 'Port', ports(i), 'Tag', '');
%                     if isempty(app.TurboSerial)
%                         app.TurboSerial = serial(ports(i));
%                     else
%                         fclose(app.TurboSerial);
%                         app.TurboSerial = app.TurboSerial(1);
%                     end
%                     fopen(app.TurboSerial);
%                     set(app.TurboSerial, 'Terminator', {'CR','CR'});
%                     set(app.TurboSerial, 'Timeout', 0.2);%May want to lower this to adjsut this 
%                     %if it takes too long or if it skips corrects ports
%                     
%                     %so I do not know the device address for the turbo
%                     %pump. i think this will work though. This tries every
%                     %possible address. Currently this will look through
%                     %each possible address for each com port. This avoids
%                     %hardcoding, but hard coding may definately be a faster
%                     %better option once we know hte device address is
%                     %constant. (or maybe it changes IDEK)
%                     for j = 1:255
%                         app.TurboAddress = int2str(j);
%                         if not(strcmp(getRPM(app),'failed'))
%                             Update_Log(app,'Successfully connected to Turbo Pump')
%                             Update_Log(app,'Device address is ',int2str(j))
%                             CheckTurboStatus(app)
%                             comport = ports(i);
%                             connected = true;
%                             break
%                         end
%                     end
%                     
%                     %if you want to hard code the device address, update and
%                     %uncomment the next lines then comment the above for
%                     %loop
% %                     app.TurboAddress = *insert address here as a string*;
% %                     if not(strcmp(getRPM(app),'failed'))
% %                         Update_Log(app,'Successfully connected to Turbo Pump')
% %                         Update_Log(app,'Device address is ',int2str(j))
% %                         CheckTurboStatus(app)
% %                         comport = ports(i);
% %                         connected = true;
% %                         break
% %                     end
%                     if connected
%                         break
%                     else
%                         fclose(app.TurboSerial);
%                         delete(app.TurboSerial);
%                         clear app.TurboSerial;
%                     end
%                     
%                 end
%                 
%                 
%                 if isempty(comport)
%                     Update_Log(app,'Could not locate turbo pump COM port')
%                     error('Communication not successful')
%                 end
%                 
%                 
%                 CheckTurboStatus(app)
                ChangeInterlock(app,'turbo')
            catch
                Update_Log(app,'Error connecting to turbo pump')
                Change_Status(app,'Off')
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if strcmp('Ready',app.ProgramStatusEditField.Value)
                app.TZero = now;
                Change_Status(app,'Active')
%                 try%this 'try' is due to an error thrown from the while condition upon closing the app 
                    if strcmp(app.SyncModeSwitch.Value, 'Automatic')
                       while app.ProgramStatusEditField.Value  == "Active" && not(app.IsSynced)
                            Update_Data(app)
                            if (app.Z1Temps(end) + app.Z2Temps(end) + app.Z3Temps(end))/3 >= app.DegassingTempCEditField.Value - app.TempTol/2
                                if (app.Z1Temps(end) + app.Z2Temps(end) + app.Z3Temps(end))/3 >= app.DegassingTempCEditField.Value + app.TempTol/2
                                    Update_Log(app,'Sync aborted. Furnace too hot')
                                else
                                    Update_Log(app,'Beginning Clock Synchronization')
                                    SyncTime(app)
                                end
                            end
                            pause(1)
                        end
                    elseif strcmp(app.SyncModeSwitch.Value,'Manual')
                        app.TZero = now;
                        Update_Data(app)
                        app.FurnaceDelay = app.FurnTime(end) - app.FurnTime(1);
                        app.SyncStatusLamp.Color = 'g';
                        app.NotSyncedLabel.Text = 'Synced';
                        app.IsSynced = true;
                        app.UScaleChanged = true;
                        Update_Log(app,['Synced zeropoint at ',datestr(app.TZero)])
                        Update_Log(app,'Successfully Synced')
                    else
                        Change_Status(app,'Off')
                        Update_Log(app,'Sync Mode Error')
                    end
                    while app.ProgramStatusEditField.Value  == "Active"
                        %update data is where all the graphing and updating
                        %occurs. It is the heart and soul of this GUI
                        Update_Data(app)
                        pause(1)
                    end
%                 catch ME
%                     switch ME.identifier
%                         case 'MATLAB:class:InvalidHandle'
%                             %warning('App has already been deleted');
%                         otherwise
%                             ME.message
%                     end
%                 end
            elseif strcmp('Off',app.ProgramStatusEditField.Value)
                errordlg('Cannot start program unless interlock is complete','Uncomplete Interlock');
            else
                errordlg('Cannot start program while furnace is busy','Furnace Busy');
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
%             readFileName = ['Data Files/Furnace Data Files/', app.FurnaceDataFileDropDown.Value];
%             copiedFileName = ['Backup Files/Copyof',app.FurnaceDataFileDropDown.Value];
%             copyfile(readFileName, copiedFileName)
            Change_Status(app,'Off')
        end

        % Value changed function: SyncModeSwitch
        function SyncModeSwitchValueChanged(app, event)
           if strcmp(app.SyncModeSwitch.Value,'Manual')
                app.DegassingTempCEditField.Enable = 'off';
           else
                app.DegassingTempCEditField.Enable = 'on';
           end
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            app.UScaleChanged = true;
            if strcmp(app.Switch.Value,'Custom Time')
                app.CustomControl = CustomTime(app);
            else
                try
                    delete(app.CustomControl)
                catch 
                end
            end
        end

        % Value changed function: DisplaySettingsSwitch
        function DisplaySettingsSwitchValueChanged(app, event)
            app.UScaleChanged = true;
            if strcmp(app.Switch.Value, 'Custom Time')
                app.CustomControl.UIFigure.Visible = 'off';
                app.CustomControl.UIFigure.Visible = 'on';
            end
        end

        % Button pushed function: RefreshFilesButton
        function RefreshFilesButtonPushed(app, event)
            try
                f = dir('Data Files/Furnace Data Files/');
                files = {};
                if length(f)<3
                    error('No furnace data files were found')
                end
                for i = 3:length(f)
                    file = f(i);
                    files(i-2) = {file.name};
                end
                app.FurnaceDataFileDropDown.Items = files;
            catch
                Update_Log(app,'No furnace data files were found')
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Furance Temperature History')
            xlabel(app.UIAxes, 'Time (min)')
            ylabel(app.UIAxes, 'Temperature (C)')
            zlabel(app.UIAxes, 'Z')
            app.FurnaceDataPanel = uipanel(app.UIFigure);
            app.FurnaceDataPanel.Title = 'Furnace Data';
            app.FurnaceDataFileDropDownLabel = uilabel(app.FurnaceDataPanel);
            app.FurnaceDataFileDropDownLabel.Text = 'Furnace Data File';
            app.FurnaceDataFileDropDown = uidropdown(app.FurnaceDataPanel);
            app.FurnaceDataFileDropDown.Items = {};
            app.FurnaceDataFileDropDown.Value = {};
            app.CheckFuranceFileButton = uibutton(app.FurnaceDataPanel, 'push');
            app.CheckFuranceFileButton.ButtonPushedFcn = createCallbackFcn(app, @CheckFuranceFileButtonPushed, true);
            app.CheckFuranceFileButton.Text = 'Check Furance File';
            app.RefreshFilesButton = uibutton(app.FurnaceDataPanel, 'push');
            app.RefreshFilesButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshFilesButtonPushed, true);
            app.RefreshFilesButton.Text = 'Refresh Files';
            app.SystemLogTextAreaLabel = uilabel(app.UIFigure);
            app.SystemLogTextAreaLabel.Text = 'System Log';
            app.SystemLogTextArea = uitextarea(app.UIFigure);
            app.SystemLogTextArea.Editable = 'off';
            app.TripPointsPanel = uipanel(app.UIFigure);
            app.TripPointsPanel.Title = 'Trip Points';
            app.FurnaceTempCEditFieldLabel = uilabel(app.TripPointsPanel);
            app.FurnaceTempCEditFieldLabel.Text = 'Furnace Temp (C)';
            app.FurnaceTempCEditField = uieditfield(app.TripPointsPanel, 'numeric');
            app.FurnaceTempCEditField.Limits = [-273.15 Inf];
            app.FurnaceTempCEditField.Value = 1100;
            app.FurnaceSyncingControlsPanel = uipanel(app.UIFigure);
            app.FurnaceSyncingControlsPanel.Title = 'Furnace Syncing Controls';
            app.SyncStatusLampLabel = uilabel(app.FurnaceSyncingControlsPanel);
            app.SyncStatusLampLabel.Text = 'Sync Status';
            app.SyncStatusLamp = uilamp(app.FurnaceSyncingControlsPanel);
            app.NotSyncedLabel = uilabel(app.FurnaceSyncingControlsPanel);
            app.NotSyncedLabel.Text = 'Not Synced';
            app.DegassingTempCEditFieldLabel = uilabel(app.FurnaceSyncingControlsPanel);
            app.DegassingTempCEditFieldLabel.Text = 'Degassing Temp (C)';
            app.DegassingTempCEditField = uieditfield(app.FurnaceSyncingControlsPanel, 'numeric');
            app.DegassingTempCEditField.Enable = 'off';
            app.DegassingTempCEditField.Value = 50;
            app.SyncModeSwitchLabel = uilabel(app.FurnaceSyncingControlsPanel);
            app.SyncModeSwitchLabel.Text = 'Sync Mode';
            app.SyncModeSwitch = uiswitch(app.FurnaceSyncingControlsPanel, 'toggle');
            app.SyncModeSwitch.Items = {'Automatic', 'Manual'};
            app.SyncModeSwitch.ValueChangedFcn = createCallbackFcn(app, @SyncModeSwitchValueChanged, true);
            app.SyncModeSwitch.Value = 'Manual';
            app.MasterControlsPanel = uipanel(app.UIFigure);
            app.MasterControlsPanel.Title = 'Master Controls';
            app.StartButton = uibutton(app.MasterControlsPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Enable = 'off';
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.MasterControlsPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop';
            app.ProgramStatusEditFieldLabel = uilabel(app.MasterControlsPanel);
            app.ProgramStatusEditFieldLabel.Text = {'Program'; 'Status'};
            app.ProgramStatusEditField = uieditfield(app.MasterControlsPanel, 'text');
            app.ProgramStatusEditField.Editable = 'off';
            app.ProgramStatusEditField.Value = 'Off';
            app.Lamp = uilamp(app.MasterControlsPanel);
            app.PlottingSettingsPanel = uipanel(app.UIFigure);
            app.PlottingSettingsPanel.Title = 'Plotting Settings';
            app.DisplaySettingsSwitch = uiswitch(app.PlottingSettingsPanel, 'toggle');
            app.DisplaySettingsSwitch.Items = {'Elasped Time', 'Actual Time'};
            app.DisplaySettingsSwitch.ValueChangedFcn = createCallbackFcn(app, @DisplaySettingsSwitchValueChanged, true);
            app.DisplaySettingsSwitch.Enable = 'off';
            app.DisplaySettingsSwitch.Value = 'Elasped Time';
            app.Switch = uiswitch(app.PlottingSettingsPanel, 'toggle');
            app.Switch.Items = {'All Time', 'Custom Time'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Enable = 'off';
            app.Switch.Value = 'All Time';
            app.MeasuredValuesPanel = uipanel(app.UIFigure);
            app.MeasuredValuesPanel.Title = 'Measured Values';
            app.UITable = uitable(app.MeasuredValuesPanel);
            app.UITable.ColumnName = {'Actual Temp (C)'};
            app.UITable.RowName = {'Zone 1'; 'Zone 2'; 'Zone 3'};
            app.TurboRPMEditFieldLabel = uilabel(app.MeasuredValuesPanel);
            app.TurboRPMEditFieldLabel.Text = 'Turbo RPM';
            app.TurboRPMEditField = uieditfield(app.MeasuredValuesPanel, 'numeric');
            app.TurboStatusLabel = uilabel(app.MeasuredValuesPanel);
            app.TurboStatusLabel.Text = {'Turbo'; 'Status'};
            app.TurboStatusLamp = uilamp(app.MeasuredValuesPanel);
            app.DigitalInterlockPanel = uipanel(app.UIFigure);
            app.DigitalInterlockPanel.Title = 'Digital Interlock';
            app.FurnaceLampLabel = uilabel(app.DigitalInterlockPanel);
            app.FurnaceLampLabel.Text = 'Furnace';
            app.FurnaceLamp = uilamp(app.DigitalInterlockPanel);
            app.TurboPumpLabel = uilabel(app.DigitalInterlockPanel);
            app.TurboPumpLabel.Text = {'Turbo'; 'Pump'};
            app.TurboPumpLamp = uilamp(app.DigitalInterlockPanel);
            app.TurboControlPanel = uipanel(app.UIFigure);
            app.TurboControlPanel.Title = 'Turbo Control';
            app.ConnecttoTurboButton = uibutton(app.TurboControlPanel, 'push');
            app.ConnecttoTurboButton.ButtonPushedFcn = createCallbackFcn(app, @ConnecttoTurboButtonPushed, true);
            app.ConnecttoTurboButton.Text = 'Connect to Turbo';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AnnealingGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end