classdef CVDSystemGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UIFormatting                    matlab.ui.control.Button
        UIBackground                    matlab.ui.control.Button
        DigitalInterlockPanel           matlab.ui.container.Panel
        MassFlowLamp                    matlab.ui.control.Lamp
        MassFlowLampLabel               matlab.ui.control.Label
        BubblerHeatersLamp              matlab.ui.control.Lamp
        BubblerHeatersLabel             matlab.ui.control.Label
        ButterflyValveLamp              matlab.ui.control.Lamp
        ButterflyValveLabel             matlab.ui.control.Label
        FurnaceLamp                     matlab.ui.control.Lamp
        FurnaceLampLabel                matlab.ui.control.Label
        PlottingSettingsPanel           matlab.ui.container.Panel
        DisplaySettingsPanel            matlab.ui.container.Panel
        DisplayMeasuredPressureCheckBox  matlab.ui.control.CheckBox
        DisplaySetPressureCheckBox      matlab.ui.control.CheckBox
        TimeScaleControlPanel           matlab.ui.container.Panel
        DisplaySettingsSwitch           matlab.ui.control.ToggleSwitch
        DisplaySettingsSwitchLabel      matlab.ui.control.Label
        Switch                          matlab.ui.control.ToggleSwitch
        FileSelectionPanel              matlab.ui.container.Panel
        RefreshFilesButton              matlab.ui.control.Button
        MassFlowControlDataPanel        matlab.ui.container.Panel
        ConnecttoMFCCButton             matlab.ui.control.Button
        CreateMFCProfileButton          matlab.ui.control.Button
        MassFlowProfileDropDown         matlab.ui.control.DropDown
        PreviewNbCl5Button              matlab.ui.control.Button
        ArMFCProfileDropDown            matlab.ui.control.DropDown
        ArMFCProfileDropDownLabel       matlab.ui.control.Label
        SnCl2MFCProfileDropDown         matlab.ui.control.DropDown
        SnCl2MFCProfileDropDownLabel    matlab.ui.control.Label
        NbCl5MFCProfileDropDown         matlab.ui.control.DropDown
        NbCl5MFCProfileDropDownLabel    matlab.ui.control.Label
        BubblerHeaterDataPanel          matlab.ui.container.Panel
        CheckHeaterFilesButton          matlab.ui.control.Button
        BubblerFileDropDown_2           matlab.ui.control.DropDown
        FurnaceDataFileDropDownLabel_3  matlab.ui.control.Label
        BubblerFileDropDown             matlab.ui.control.DropDown
        FurnaceDataFileDropDownLabel_2  matlab.ui.control.Label
        PressureDataPanel               matlab.ui.container.Panel
        ConnecttoButterflyValveButton   matlab.ui.control.Button
        CreatePressureProfileButton     matlab.ui.control.Button
        PreviewPressureProfileButton    matlab.ui.control.Button
        PressureProfileDropDown         matlab.ui.control.DropDown
        PressureProfileDropDownLabel    matlab.ui.control.Label
        FurnaceDataPanel                matlab.ui.container.Panel
        CheckFuranceFileButton          matlab.ui.control.Button
        FurnaceDataFileDropDown         matlab.ui.control.DropDown
        FurnaceDataFileDropDownLabel    matlab.ui.control.Label
        MasterControlsPanel             matlab.ui.container.Panel
        Lamp                            matlab.ui.control.Lamp
        ProgramStatusEditField          matlab.ui.control.EditField
        ProgramStatusLabel              matlab.ui.control.Label
        StopButton                      matlab.ui.control.Button
        StartButton                     matlab.ui.control.Button
        FurnaceSyncingControlsPanel     matlab.ui.container.Panel
        SyncModeInfoButton              matlab.ui.control.Button
        SyncModeSwitch                  matlab.ui.control.ToggleSwitch
        SyncModeSwitchLabel             matlab.ui.control.Label
        DegassingTempCEditField         matlab.ui.control.NumericEditField
        DegassingTempCEditFieldLabel    matlab.ui.control.Label
        NotSyncedLabel                  matlab.ui.control.Label
        SyncStatusLamp                  matlab.ui.control.Lamp
        SyncStatusLampLabel             matlab.ui.control.Label
        TripPointsPanel                 matlab.ui.container.Panel
        PressureTorrEditField_2         matlab.ui.control.NumericEditField
        PressureTorrEditField_2Label    matlab.ui.control.Label
        FurnaceTempCEditField           matlab.ui.control.NumericEditField
        FurnaceTempCEditFieldLabel      matlab.ui.control.Label
        SystemLogTextArea               matlab.ui.control.TextArea
        SystemLogTextAreaLabel          matlab.ui.control.Label
        MeasuredValuesPanel             matlab.ui.container.Panel
        UITable3                        matlab.ui.control.Table
        ValveOpennessEditField          matlab.ui.control.NumericEditField
        ValveOpennessEditFieldLabel     matlab.ui.control.Label
        UITable2                        matlab.ui.control.Table
        PressureTorrEditField           matlab.ui.control.NumericEditField
        PressureTorrEditFieldLabel      matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        UIAxes_4                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    %here is where all of the global variables are defined
    %to access these variables, they are app.*insertName*
    properties (Access = public)
        %%Data Display Variables
        %these are the current measured temp for the zones in celcius
        Z1ActTemp = 0;
        Z2ActTemp = 0;
        Z3ActTemp = 0;
        
        %this will contain the times in units of 24 hours collected from
        %the furnace. The first time will be set to 0
        FurnTime
        %ScaledFurnTime has the version of the the times in the units that
        %are being plotted. for example, it could contain the times as
        %dates, or in units of secs etc. This helps reduce rescaling data
        %each time.
        ScaledFurnTime
        
        %these contain all the previous zone temps in celcius
        Z1Temps
        Z2Temps
        Z3Temps
        
        %this contains the uncontrolled data points for bubblers in min and C
        %the scaled times are in whatever units the plotting requires to
        %reduce rescaling. Instead of rescaling the NbTempTime each time,
        %we append new scaled data to SclaedNbTempTime
        NbTempTime
        ScaledNbTempTime
        NbTemp
        SnTempTime
        ScaledSnTempTime
        SnTemp
        
        %this contains the times collected from matlab for pres, mf, etc.
        %It is also in units of 24 hours, and will just be in matlab's
        %serial date number. The first point in this list is always defined
        %to be 0, and is the moment the furnace (and other uncontrolled devices) and matlab sync.
        Times
        ScaledTime
        
        %this contains all the pressures in torr
        Pres
        
        %These contain the pressures and times coresponding to the set
        %pressure profile. The times are in minutes and the pressure is in
        %torr
        SetPresTime
        SetPressures
        
        %this is the current measured pressure measured by the butterfly
        %valve's connected sensor in units of torr
        ActPres = 760;
        
        %these contain all the measrued values. The time is exactly
        %app.Times like pressure. These are the analog for Pres
        ArFlow
        NbFlow
        SnFlow
        
        %these will contain the data for plotting the set flow rates. Time
        %is in units of min and flow is in units of sccm. These plot in the
        %background, so they need their own time scales
        NbSetFlow
        NbSetFlowTime
        SnSetFlow
        SnSetFlowTime
        ArSetFlow
        ArSetFlowTime
        ScaledNbSetFlowTime
        ScaledSnSetFlowTime
        ScaledArSetFlowTime
        
        %these are the measured mass flow values
        ArActFlow = 0;
        NbActFlow = 0;
        SnActFlow = 0;
        HActFlow = 0;


        %%other plotting parameters
        %this is the matlab's serial date number for the moment that the
        %furnace and the matlab times are synced
        TZero
        
        %This is the time between the moment the furnace software's program 
        %began, and the sync point in units of 24 hours. Note, for manual
        %sync, FurnaceDelay is defined to be how much time has passed from
        %the start ofdata collecting to the time the start button was hit
        FurnaceDelay = 0;
        
        %this is the is the point (1,1) of the furnace data and bubbler 
        %heaters data. These are the times that the first furnace temp
        %and bubbler temps was recorded
        FurnStart
        NbBubStart
        SnBubStart
        
        %This is difference in days between matlab serial date number and
        %the itools serial date number. As far as I can tell, it seems to
        %be very close to exact this number in days.
        Matlab_iTools_TimeDif = 693960;
        
        %This is the a date string that is initated during start up that
        %can be used to name and distinguish log and pressure files.
        TimeStarted
        
        %This stores the info from the CustomTime app
        CustomControl

        %this tells the update_data function that it needs to rescale all
        %the data
        CScaleChanged = true;
        UScaleChanged = true;
        
        %this is the length of the pause in units of seconds
        PauseLength = 1


        %%Syncing variables
        %this is a boolean to know if the two times have been synced
        IsSynced = false;
        
        %this is the temperature tolerance of plus minus 5 degrees celcius.
        %This is exculsively used for automatic syncing of the furance.
        TempTol = 5


        %%Misc. Butterfly Valve variables
        %This is the serial connection between the butterfly valve and the
        %gui
        ValveSerial
        
        %This string gives the resolution of the pressure measurement that 
        %the butterfly valve outputs to matlab. Must be a seven digit
        %string. It is currently set to highest value for best resolution,
        %although the pressure sensor is accurate to +/-0.02 torr
        PresRes = '0999999';
    end
    
    %here is where all the functions are defined that can be used later
    methods (Access = private)
        
        function Update_Log(app,msg)
            %this function updates the system log, so the history of the
            %program is easily and conveniently displayed. It also saves
            %the log text in a data file for later viewing.
            if strcmp(app.SystemLogTextArea.Value,'')
                app.SystemLogTextArea.Value = [datestr(now),': ' msg];
            else
                app.SystemLogTextArea.Value = [app.SystemLogTextArea.Value; datestr(now),': ' msg];
                
            end
            pause(.1)%this pause lets the text box notice the change in value
            %so it scrolls all the way down
            scroll(app.SystemLogTextArea,'bottom')
            
            %uncomment next 3 lines for actual operation:
%             datafile = fopen(['Data Files/System Log Files/',app.TimeStarted,'.txt'],'a');
%             fprintf(datafile,'%s\n',app.SystemLogTextArea.Value{length(app.SystemLogTextArea.Value)});
%             fclose(datafile);
        end
        
        function ChangeInterlock(app,element)
            switch element
                %this function is how the GUI changes the interlock. This
                %function has the ability to make appropiate changes to the
                %interlock and then detects when the program is ready to
                %run.
                case 'furnace'
                    readfilename = ['Data Files/Furnace Data Files/', app.FurnaceDataFileDropDown.Value];
                    copiedfilename = ['Backup Files/Copyof',app.FurnaceDataFileDropDown.Value];
                    copyfile(readfilename, copiedfilename)  
                    Update_Log(app,['Furnace will run ',app.FurnaceDataFileDropDown.Value])
                    app.CheckFuranceFileButton.BackgroundColor = [.94,.94,.94];%gray
                    app.FurnaceLamp.Color = 'g';
                case 'clear furnace'
                    app.CheckFuranceFileButton.BackgroundColor = [.39,.83,.07];%green
                    app.FurnaceLamp.Color = 'r';
                case 'butterfly valve'
                    app.ConnecttoButterflyValveButton.BackgroundColor = [.94,.94,.94];
                    app.ButterflyValveLamp.Color = 'g';
                case 'clear butterfly valve'
                    app.ConnecttoButterflyValveButton.BackgroundColor = [.39,.83,.07];
                    app.ButterflyValveLamp.Color = 'r';
                case 'bubbler heaters'
                    Update_Log(app,['Nb bubbler will run ',app.BubblerFileDropDown.Value])
                    Update_Log(app,['Sn bubbler will run ',app.BubblerFileDropDown_2.Value])
                    app.CheckHeaterFilesButton.BackgroundColor = [.94,.94,.94];
                    app.BubblerHeatersLamp.Color = 'g';
                case 'clear bubbler heaters'
                    app.CheckHeaterFilesButton.BackgroundColor = [.39,.83,.07];
                    app.BubblerHeatersLamp.Color = 'r';
                case 'MFC'
                    Update_Log(app,['NbCl5 MFC will run ',app.NbCl5MFCProfileDropDown.Value])
                    Update_Log(app,['SnCl2 MFC will run ',app.SnCl2MFCProfileDropDown.Value])
                    Update_Log(app,['Ar MFC will run ',app.ArMFCProfileDropDown.Value])
                    app.ConnecttoMFCCButton.BackgroundColor = [.94,.94,.94];
                    app.MassFlowLamp.Color = 'g';
                case 'clear MFC'
                    app.ConnecttoMFCCButton.BackgroundColor = [.39,.83,.07];
                    app.MassFlowLamp.Color = 'r';
            end
            if isequal(app.FurnaceLamp.Color, [0 1 0]) && isequal(app.ButterflyValveLamp.Color, [0,1,0]) && isequal(app.BubblerHeatersLamp.Color, [0,1,0]) && isequal(app.MassFlowLamp.Color, [0,1,0])
                Change_Status(app,'Ready')
            else
                app.Lamp.Color = [0.90,0.90,0.90];
                app.StartButton.BackgroundColor = [.94,.94,.94];
                app.StartButton.Enable = 'Off';
                app.ProgramStatusEditField.Value  = 'Off';
            end
        end
        
        function Change_Status(app,status)
            %this fuction performs all the actions required when the status
            %of the program changes
            switch status
                case 'Off'
                    app.Lamp.Color = [0.90,0.90,0.90];
                    app.SyncStatusLamp.Color = 'r';
                    app.NotSyncedLabel.Text = 'Not Synced';
                    app.SyncModeSwitch.Enable = 'on';
                    app.IsSynced = false;
                    %clear plots
                    cla(app.UIAxes)
                    cla(app.UIAxes_2)
                    cla(app.UIAxes_3)
                    cla(app.UIAxes_4)
                    legend(app.UIAxes,'hide')
                    legend(app.UIAxes_2,'hide')
                    legend(app.UIAxes_3,'hide')
                    legend(app.UIAxes_4,'hide')
                    %reset buttons
                    app.DisplaySetPressureCheckBox.Enable = 'off';
                    app.DisplayMeasuredPressureCheckBox.Enable = 'off';
                    app.StopButton.Enable = 'off';
                    app.StartButton.Enable = 'off';
                    app.StartButton.BackgroundColor = [.94,.94,.94];
                    app.FurnaceDataFileDropDown.Enable = 'on';
                    app.CheckFuranceFileButton.Enable = 'on';
                    app.CheckFuranceFileButton.BackgroundColor = [.39,.83,.07];
                    app.ConnecttoButterflyValveButton.BackgroundColor = [.39,.83,.07];
                    app.CheckHeaterFilesButton.BackgroundColor = [.39,.83,.07];
                    app.ConnecttoMFCCButton.BackgroundColor = [.39,.83,.07];
                    app.ConnecttoButterflyValveButton.Enable = 'on';
                    app.CheckHeaterFilesButton.Enable = 'on';
                    app.ConnecttoMFCCButton.Enable = 'on';
                    app.PressureProfileDropDown.Enable = 'on';
                    app.BubblerFileDropDown.Enable = 'on';
                    app.BubblerFileDropDown_2.Enable = 'on';
                    app.ArMFCProfileDropDown.Enable = 'on';
                    app.NbCl5MFCProfileDropDown.Enable = 'on';
                    app.SnCl2MFCProfileDropDown.Enable = 'on';
                    %reset interlock
                    app.FurnaceLamp.Color = 'r';
                    app.ButterflyValveLamp.Color = 'r';
                    app.BubblerHeatersLamp.Color = 'r';
                    app.MassFlowLamp.Color = 'r';
                    %reset time and pressure data
                    app.Pres = [];
                    app.Times = [];
                    app.CScaleChanged = true;
                    app.UScaleChanged = true;
                    app.DisplaySettingsSwitch.Value = 'Elasped Time';
                    app.Switch.Value = 'All Time';
                    app.DisplaySettingsSwitch.Enable = 'off';
                    app.Switch.Enable = 'off';
                    try
                        delete(app.CustomControl)
                        %this is the only way to delete the remote. it
                        %cannot be manually closed
                    catch 
                    end
                case 'Active'
                    app.Lamp.Color = 'g';
                    app.StartButton.Enable = 'off';
                    app.CheckFuranceFileButton.Enable = 'off';
                    app.FurnaceDataFileDropDown.Enable = 'off';
                    app.SyncModeSwitch.Enable = 'off';
                    app.DisplaySetPressureCheckBox.Enable = 'on';
                    app.DisplayMeasuredPressureCheckBox.Enable = 'on';
                    app.StopButton.Enable = 'on';
                    app.ConnecttoButterflyValveButton.Enable = 'off';
                    app.ConnecttoMFCCButton.Enable = 'off';
                    app.CheckHeaterFilesButton.Enable = 'off';
                    app.PressureProfileDropDown.Enable = 'off';
                    app.BubblerFileDropDown.Enable = 'off';
                    app.BubblerFileDropDown_2.Enable = 'off';
                    app.ArMFCProfileDropDown.Enable = 'off';
                    app.NbCl5MFCProfileDropDown.Enable = 'off';
                    app.SnCl2MFCProfileDropDown.Enable = 'off';
                    app.DisplaySettingsSwitch.Enable = 'on';
                    app.Switch.Enable = 'on';
                case 'Critical'
                    app.Lamp.Color = 'r';
                    app.StartButton.Enable = 'off';
                    app.CheckFuranceFileButton.Enable = 'off';
                    app.StopButton.Enable = 'off';
                case 'Ready'
                    app.Lamp.Color = 'c';
                    app.StartButton.BackgroundColor = [.39,.83,.07];%green
                    app.StartButton.Enable = 'On';
                otherwise
                    errordlg(['Cannot Recongize status: ',status],'Status Error');
            end
            if not(strcmp(status,app.ProgramStatusEditField.Value))
                Update_Log(app,['Status changed from ', app.ProgramStatusEditField.Value,' to ',status])
                app.ProgramStatusEditField.Value  = status;
            end            
        end
        
        function Update_Data(app)
            %this function is realy the meat of the whole GUI. It displays
            %and plots all the data in real time. 
            
            %These ifs will make the font red if a value is over trip point
            %for quicker visual recognition
            if app.Z1ActTemp < app.FurnaceTempCEditField.Value
                addStyle(app.UITable,uistyle('FontColor' , 'black'),'cell',[1,1]);
            else
                addStyle(app.UITable,uistyle('FontColor' , 'red'),'cell',[1,1]);
            end
            if app.Z2ActTemp < app.FurnaceTempCEditField.Value
                addStyle(app.UITable,uistyle('FontColor' , 'black'),'cell',[2,1]);
            else
                addStyle(app.UITable,uistyle('FontColor' , 'red'),'cell',[2,1]);
            end
            if app.Z3ActTemp < app.FurnaceTempCEditField.Value
                addStyle(app.UITable,uistyle('FontColor' , 'black'),'cell',[3,1]);
            else
                addStyle(app.UITable,uistyle('FontColor' , 'red'),'cell',[3,1]);
            end
            
            %% This section reads the uncontrolled data
            try
                furnaceData = readmatrix(strcat('Data Files/Furnace Data Files/', app.FurnaceDataFileDropDown.Value));
                furnaceData(:,1) = (furnaceData(:,1) - app.FurnStart);
                app.FurnTime = furnaceData(:,1);
                app.Z1Temps = furnaceData(:,2);
                app.Z2Temps = furnaceData(:,3);
                app.Z3Temps = furnaceData(:,4);
                app.Z1ActTemp = app.Z1Temps(end);
                app.Z2ActTemp = app.Z2Temps(end);
                app.Z3ActTemp = app.Z3Temps(end);
                app.UITable.Data = table([app.Z1ActTemp;app.Z2ActTemp;app.Z3ActTemp]);
            catch
                Update_Log(app,'Error reading furnace data file')
                Change_Status(app,'Off')
            end
            try
                nbBubData = readtable(strcat('Data Files/Bubbler Files/', app.BubblerFileDropDown.Value));
                snBubData = readtable(strcat('Data Files/Bubbler Files/', app.BubblerFileDropDown_2.Value));
                %readtable must be used because readmatrix does not
                %support values of different types
                app.NbTempTime = datenum(table2array(nbBubData(:,1))+today)-app.TZero;
                app.SnTempTime = datenum(table2array(snBubData(:,1))+today)-app.TZero;
                %there will be a display issue if the recording crosses
                %midnight, but it does not have any impact on any actual
                %daat collection
                app.NbTemp = table2array(nbBubData(:,2));
                app.SnTemp = table2array(snBubData(:,2));
                app.UITable3.Data = table([app.NbTemp(end);app.SnTemp(end)]);
            catch
                Update_Log(app,'Error reading bubbler data files')
                Change_Status(app,'Off')
            end
            %now we graph the uncontrolled data
            if app.UScaleChanged
                app.UScaleChanged = false;% I set this right away to minimize the effects of
                %switching scale while Rescale_Data is running
                Rescale_UCData(app)%this rescales and plots all the points
            else
                Append_UCData(app)%this only adds points in proper format w/o changing old data
            end
            
            
            %% now we collect the controlled data if synced
            if app.IsSynced
                if app.PressureTorrEditField.Value < app.PressureTorrEditField_2.Value
                    app.PressureTorrEditField.FontColor = 'black';
                else
                    app.PressureTorrEditField.FontColor = 'red';
                end
                app.Times = [app.Times;now-app.TZero];
                app.ActPres = QueryValve(app,'P:');
                app.ValveOpennessEditField.Value = QueryValve(app,'A:');
                app.Pres = [app.Pres;app.ActPres];
                app.PressureTorrEditField.Value = app.ActPres;
%                 SetPres(app)
%                 SetMFC(app)
                app.UITable2.Data = table([app.ArActFlow;app.NbActFlow;app.SnActFlow;app.HActFlow]);
                
                %this plots the synced data
                if app.CScaleChanged
                    app.CScaleChanged = false;
                    Rescale_CData(app)
                else
                    Append_CData(app)
                end
            end
        end
        
        function Rescale_UCData(app)
            try
                %all these nested ifs and swithces look intimidating, 
                %but I assure you that they are not that bad.
                %All that is happening is a custom time adjustment, or
                %an all time adjustment. This first part assumes
                %elapsed time is on. If it is set to actual time, the
                %correction is made below
                
                %added note, this is where you should add plotting of
                %things that are uncontrolled (not started by the program). 
                %For example the furnace and the bubbler heaters, which must 
                %be synced before the plotting of the controlled devices begins
                
                %this is really just a huge chuck of if and switches for
                %the sole purpose of being able to adjust the time
                %displayed. If it is too slow, or bad at garbage
                %collection, hardcoding may be the option. If so, this can
                %be used as a model
                if strcmp(app.Switch.Value, 'Custom Time')
                    switch app.CustomControl.DropDown.Value 
                        case 'min'
                            app.ScaledFurnTime = (app.FurnTime-app.FurnaceDelay)*1440;
                            app.ScaledNbTempTime = (app.NbTempTime)*1440;
                            app.ScaledSnTempTime = (app.SnTempTime)*1440;
                            app.UIAxes.XLabel.String = 'Time (min)';
                            app.UIAxes_3.XLabel.String = 'Time (min)';
                            if app.CustomControl.DisplayLastTime.Value
                                futLims = [app.ScaledFurnTime(end)-app.CustomControl.DisplayTimeEditField.Value,app.ScaledFurnTime(end)];
                                nbFutLims = [app.ScaledNbTempTime(end)-app.CustomControl.DisplayTimeEditField.Value,app.ScaledNbTempTime(end)];
                                snFutLims = [app.ScaledSnTempTime(end)-app.CustomControl.DisplayTimeEditField.Value,app.ScaledSnTempTime(end)];
                            else
                                switch app.CustomControl.DropDown_2.Value
                                    case 'min'
                                        futLims = [app.CustomControl.StartingTimeSpinner.Value,app.CustomControl.StartingTimeSpinner.Value+app.CustomControl.DisplayTimeEditField.Value];
                                    case 'hr'
                                        futLims =[60*app.CustomControl.StartingTimeSpinner.Value,60*app.CustomControl.StartingTimeSpinner.Value+app.CustomControl.DisplayTimeEditField.Value];
                                    case 'sec'
                                        futLims =[app.CustomControl.StartingTimeSpinner.Value/60,app.CustomControl.StartingTimeSpinner.Value/60+app.CustomControl.DisplayTimeEditField.Value];
                                end
                                nbFutLims = futLims;
                                snFutLims = futLims;
                            end
                        %notice, I save the XLims as futlims instead of
                        %immediately changing the UIaxes. This is because
                        %an error will be thrown when switching from
                        %elasped time to actual time, due to changing data
                        %types for the x axis
                        
                        %this is solved by plotting the data first, then
                        %scaling it
                        case 'hr'
                            app.ScaledFurnTime = (app.FurnTime-app.FurnaceDelay)*24;
                            app.ScaledNbTempTime = (app.NbTempTime)*24;
                            app.ScaledSnTempTime = (app.SnTempTime)*24;
                            app.UIAxes.XLabel.String = 'Time (hr)';
                            app.UIAxes_3.XLabel.String = 'Time (hr)';
                            if app.CustomControl.DisplayLastTime.Value
                                futLims = [app.ScaledFurnTime(end)-app.CustomControl.DisplayTimeEditField.Value,app.ScaledFurnTime(end)];
                                nbFutLims = [app.ScaledNbTempTime(end)-app.CustomControl.DisplayTimeEditField.Value,app.ScaledNbTempTime(end)];
                                snFutLims = [app.ScaledSnTempTime(end)-app.CustomControl.DisplayTimeEditField.Value,app.ScaledSnTempTime(end)];
                            else
                                switch app.CustomControl.DropDown_2.Value
                                    case 'hr'
                                        futLims =[app.CustomControl.StartingTimeSpinner.Value,app.CustomControl.StartingTimeSpinner.Value+app.CustomControl.DisplayTimeEditField.Value];
                                    case 'min'
                                        futLims =[app.CustomControl.StartingTimeSpinner.Value/60,app.CustomControl.StartingTimeSpinner.Value/60+app.CustomControl.DisplayTimeEditField.Value];
                                    case 'sec'
                                        futLims =[app.CustomControl.StartingTimeSpinner.Value/3600,app.CustomControl.StartingTimeSpinner.Value/3600+app.CustomControl.DisplayTimeEditField.Value];
                                        
                                end
                                nbFutLims = futLims;
                                snFutLims = futLims;
                            end
                        
                        case 'sec'
                            app.ScaledFurnTime = (app.FurnTime-app.FurnaceDelay)*86400;
                            app.ScaledNbTempTime = (app.NbTempTime)*86400;
                            app.ScaledSnTempTime = (app.SnTempTime)*86400;
                            app.UIAxes.XLabel.String = 'Time (sec)';
                            app.UIAxes_3.XLabel.String = 'Time (sec)';
                            if app.CustomControl.DisplayLastTime.Value
                                futLims = [app.ScaledFurnTime(end)-app.CustomControl.DisplayTimeEditField.Value,app.ScaledFurnTime(end)];
                                nbFutLims = [app.ScaledNbTempTime(end)-app.CustomControl.DisplayTimeEditField.Value,app.ScaledNbTempTime(end)];
                                snFutLims = [app.ScaledSnTempTime(end)-app.CustomControl.DisplayTimeEditField.Value,app.ScaledSnTempTime(end)];
                            else
                                switch app.CustomControl.DropDown_2.Value
                                    case 'sec'
                                        futLims =[app.CustomControl.StartingTimeSpinner.Value,app.CustomControl.StartingTimeSpinner.Value+app.CustomControl.DisplayTimeEditField.Value];
                                    case'min'
                                        futLims =[60*app.CustomControl.StartingTimeSpinner.Value,60*app.CustomControl.StartingTimeSpinner.Value+app.CustomControl.DisplayTimeEditField.Value];
                                    case 'hr'
                                        futLims =[app.CustomControl.StartingTimeSpinner.Value*3600,app.CustomControl.StartingTimeSpinner.Value*3600+app.CustomControl.DisplayTimeEditField.Value];
                                end
                                nbFutLims = futLims;
                                snFutLims = futLims;
                            end
                    end
                   
                    
                else %switch value is all time
                    %furnace plotting
                    if (app.FurnTime(end)-app.FurnTime(1))*1440 >240%minutes
                        app.ScaledFurnTime = (app.FurnTime-app.FurnaceDelay)*24;
                        app.UIAxes.XLabel.String = 'Time (hr)';
                    elseif (app.FurnTime(end)-app.FurnTime(1))*1440 < 1%minute
                        app.ScaledFurnTime = (app.FurnTime-app.FurnaceDelay)*86400;
                        app.UIAxes.XLabel.String = 'Time (sec)';
                    else
                        app.ScaledFurnTime = (app.FurnTime-app.FurnaceDelay)*1440;
                        app.UIAxes.XLabel.String = 'Time (min)';
                    end
                    %bubbler heater plotting
                    if (app.NbTempTime(end)-app.NbTempTime(1))*1440 >240 || (app.SnTempTime(end)-app.SnTempTime(1))*1440 >240
                        app.ScaledNbTempTime = (app.NbTempTime)*24;
                        app.ScaledSnTempTime = (app.SnTempTime)*24;
                        app.UIAxes_3.XLabel.String = 'Time (hr)';
                    elseif (app.NbTempTime(end)-app.NbTempTime(1))*1440 < 1 || (app.SnTempTime(end)-app.SnTempTime(1))*1440 < 1
                        app.ScaledNbTempTime = (app.NbTempTime)*86400;
                        app.ScaledSnTempTime = (app.SnTempTime)*86400;
                        app.UIAxes_3.XLabel.String = 'Time (sec)';
                    else
                        app.ScaledNbTempTime = (app.NbTempTime)*1440;
                        app.ScaledSnTempTime = (app.SnTempTime)*1440;
                        app.UIAxes_3.XLabel.String = 'Time (min)';
                    end
                end
                %this is the end of the elasped time nested ifs
                
                %now for the actual time adjustment if needed
                if strcmp(app.DisplaySettingsSwitch.Value, 'Actual Time')
                    app.UIAxes.XLabel.String = 'Time';
                    app.UIAxes_3.XLabel.String = 'Time';
                    %thefurn time will display the correct date that it was
                    %recorded. The bubbler heater however can only display
                    %the correct hour that it occurs, so it is very
                    %important to save the date/month in the file name, so
                    %the data aren't lost
                    app.ScaledFurnTime = datetime(app.FurnTime+(app.Matlab_iTools_TimeDif+app.FurnStart), 'ConvertFrom', 'datenum');
                    app.ScaledNbTempTime = datetime(app.TZero + app.NbTempTime, 'ConvertFrom', 'datenum');
                    app.ScaledSnTempTime = datetime(app.TZero + app.SnTempTime, 'ConvertFrom', 'datenum');
                    
                    if strcmp(app.Switch.Value, 'Custom Time')
                        switch app.CustomControl.DropDown.Value 
                            case 'min'
                                futLims = [datetime(app.Matlab_iTools_TimeDif+app.FurnStart + futLims(1)/1440+app.FurnaceDelay, 'ConvertFrom', 'datenum'), datetime(app.Matlab_iTools_TimeDif+app.FurnStart + futLims(2)/1440+app.FurnaceDelay, 'ConvertFrom', 'datenum')];
                                nbFutLims = [datetime(app.TZero + nbFutLims(1)/1440, 'ConvertFrom', 'datenum'), datetime(app.TZero + nbFutLims(2)/1440, 'ConvertFrom', 'datenum')];
                                snFutLims = [datetime(app.TZero + snFutLims(1)/1440, 'ConvertFrom', 'datenum'), datetime(app.TZero + snFutLims(2)/1440, 'ConvertFrom', 'datenum')];
                            case 'hr'
                                futLims = [datetime(app.Matlab_iTools_TimeDif+app.FurnStart + futLims(1)/24+app.FurnaceDelay,'ConvertFrom', 'datenum'), datetime(app.Matlab_iTools_TimeDif+app.FurnStart+ futLims(2)/24+app.FurnaceDelay, 'ConvertFrom', 'datenum')];
                                nbFutLims = [datetime(app.TZero + nbFutLims(1)/24, 'ConvertFrom', 'datenum'), datetime(app.TZero + nbFutLims(2)/24, 'ConvertFrom', 'datenum')];
                                snFutLims = [datetime(app.TZero + snFutLims(1)/24, 'ConvertFrom', 'datenum'), datetime(app.TZero + snFutLims(2)/24, 'ConvertFrom', 'datenum')];
                            case 'sec'
                                futLims = [datetime(app.Matlab_iTools_TimeDif+app.FurnStart +futLims(1)/86400+app.FurnaceDelay, 'ConvertFrom', 'datenum'), datetime(app.Matlab_iTools_TimeDif+app.FurnStart + futLims(2)/86400+app.FurnaceDelay, 'ConvertFrom', 'datenum')];
                                nbFutLims = [datetime(app.TZero + nbFutLims(1)/86400, 'ConvertFrom', 'datenum'), datetime(app.TZero + nbFutLims(2)/86400, 'ConvertFrom', 'datenum')];
                                snFutLims = [datetime(app.TZero + snFutLims(1)/86400, 'ConvertFrom', 'datenum'), datetime(app.TZero + snFutLims(2)/86400, 'ConvertFrom', 'datenum')];
                        end
                    end
                end
            catch ME
                ME
                Update_Log(app,'Uncontrolled hardware rescaling error')
            end
            %boom, the rescaling is done, painless right? lol. now lets
            %plot
            try
                plot(app.UIAxes,app.ScaledFurnTime,app.Z1Temps);
                hold(app.UIAxes, 'on')
                plot(app.UIAxes,app.ScaledFurnTime,app.Z2Temps);
                plot(app.UIAxes,app.ScaledFurnTime,app.Z3Temps);
                hold(app.UIAxes, 'off')
                legend(app.UIAxes,'Zone 1','Zone 2','Zone 3','location','northwest')
                
                plot(app.UIAxes_3,app.ScaledNbTempTime,app.NbTemp)
                hold(app.UIAxes_3, 'on')
                plot(app.UIAxes_3,app.ScaledSnTempTime,app.SnTemp)
                hold(app.UIAxes_3, 'off')
                legend(app.UIAxes_3,'NbCl5','SnCl2','location','northwest')
                
                %this next part selects the widest limits for the nb and
                %sn. this is a matter of taste, if you like it better, you
                %can select more narrow bounds
                if strcmp(app.Switch.Value, 'Custom Time')
                    if nbFutLims(1) <= snFutLims(1)
                        bubLims = nbFutLims(1);
                    else
                        bubLims = snFutLims(1);
                    end
                    if nbFutLims(2) >= snFutLims(2)
                        bubLims = [bubLims, nbFutLims(2)];
                    else
                        bubLims = [bubLims, snFutLims(2)];
                    end
                    app.UIAxes.XLim = futLims;
                    app.UIAxes_3.XLim = bubLims;
                else
                    app.UIAxes.XLimMode = 'auto';
                    app.UIAxes_3.XLimMode = 'auto';
                end
                
            catch ME
                ME
                Update_Log(app,'Uncontrolled Hardware Plotting Error')
            end
            %now all the previous data is stored into app.Scaledxxxxxx.
            %This means all additional data can simplely be scaled and
            %added
        end
        
        function Append_UCData(app)
            %this is only append the data if new data is added (duh)
            if length(app.ScaledFurnTime) ~= length(app.FurnTime) || length(app.ScaledNbTempTime) ~= length(app.NbTempTime) || length(app.ScaledSnTempTime) ~= length(app.SnTempTime)
                try
                    if strcmp(app.Switch.Value, 'Custom Time')
                        switch app.CustomControl.DropDown.Value 
                            case 'min'
                                %If no data is added the appendedxxxxx will
                                %be empty, otherwise it will contain all
                                %the new points to scale
                                appendedFurnTime = (app.FurnTime(length(app.ScaledFurnTime)+1:end)-app.FurnaceDelay)*1440;
                                appendedNbTime = (app.NbTempTime(length(app.ScaledNbTempTime)+1:end))*1440;
                                appendedSnTime = (app.SnTempTime(length(app.ScaledSnTempTime)+1:end))*1440;
                                if app.CustomControl.DisplayLastTime.Value
                                    try
                                        futLims = [appendedFurnTime(end)-app.CustomControl.DisplayTimeEditField.Value,appendedFurnTime(end)];
                                    catch
                                        %this will catch when the furntime
                                        %does not increase in lenght
                                        futLims = app.UIAxes.XLim;
                                    end
                                    try
                                        nbFutLims = [appendedNbTime(end)-app.CustomControl.DisplayTimeEditField.Value,appendedNbTime(end)];
                                    catch
                                        nbFutLims = app.UIAxes_3.XLim;
                                    end
                                    try
                                        snFutLims = [appendedSnTime(end)-app.CustomControl.DisplayTimeEditField.Value,appendedSnTime(end)];
                                    catch
                                        snFutLims = app.UIAxes_3.XLim;
                                    end
                                else
                                    futLims = app.UIAxes.XLim;
                                    bubLims = app.UIAxes_3.XLim;
                                end
                            
                            case 'hr'
                                appendedFurnTime = (app.FurnTime(length(app.ScaledFurnTime)+1:end)-app.FurnaceDelay)*24;
                                appendedNbTime = (app.NbTempTime(length(app.ScaledNbTempTime)+1:end))*24;
                                appendedSnTime = (app.SnTempTime(length(app.ScaledSnTempTime)+1:end))*24;
                                if app.CustomControl.DisplayLastTime.Value
                                    try
                                        futLims = [appendedFurnTime(end)-app.CustomControl.DisplayTimeEditField.Value,appendedFurnTime(end)];
                                    catch
                                        %this will catch when the furntime
                                        %does not increase in lenght
                                        futLims = app.UIAxes.XLim;
                                    end
                                    try
                                        nbFutLims = [appendedNbTime(end)-app.CustomControl.DisplayTimeEditField.Value,appendedNbTime(end)];
                                    catch
                                        nbFutLims = app.UIAxes_3.XLim;
                                    end
                                    try
                                        snFutLims = [appendedSnTime(end)-app.CustomControl.DisplayTimeEditField.Value,appendedSnTime(end)];
                                    catch
                                        snFutLims = app.UIAxes_3.XLim;
                                    end
                                else
                                    futLims = app.UIAxes.XLim;
                                    bubLims = app.UIAxes_3.XLim;
                                end
                            
                            case 'sec'
                                appendedFurnTime = (app.FurnTime(length(app.ScaledFurnTime)+1:end)-app.FurnaceDelay)*86400;
                                appendedNbTime = (app.NbTempTime(length(app.ScaledNbTempTime)+1:end))*86400;
                                appendedSnTime = (app.SnTempTime(length(app.ScaledSnTempTime)+1:end))*86400;
                                if app.CustomControl.DisplayLastTime.Value
                                    try
                                        futLims = [appendedFurnTime(end)-app.CustomControl.DisplayTimeEditField.Value,appendedFurnTime(end)];
                                    catch
                                        %this will catch when the furntime
                                        %does not increase in lenght
                                        futLims = app.UIAxes.XLim;
                                    end
                                    try
                                        nbFutLims = [appendedNbTime(end)-app.CustomControl.DisplayTimeEditField.Value,appendedNbTime(end)];
                                    catch
                                        nbFutLims = app.UIAxes_3.XLim;
                                    end
                                    try
                                        snFutLims = [appendedSnTime(end)-app.CustomControl.DisplayTimeEditField.Value,appendedSnTime(end)];
                                    catch
                                        snFutLims = app.UIAxes_3.XLim;
                                    end
                                else
                                    futLims = app.UIAxes.XLim;
                                    bubLims = app.UIAxes_3.XLim;
                                end
                        end
                       
                        
                    else %switch value is all time
                        %furnace plotting
                        if (app.FurnTime(end)-app.FurnTime(1))*1440 >240%minutes
                            appendedFurnTime = (app.FurnTime(length(app.ScaledFurnTime)+1:end)-app.FurnaceDelay)*24;
                        elseif (app.FurnTime(end)-app.FurnTime(1))*1440 < 1%minute
                            appendedFurnTime = (app.FurnTime(length(app.ScaledFurnTime)+1:end)-app.FurnaceDelay)*86400;
                        else
                            appendedFurnTime = (app.FurnTime(length(app.ScaledFurnTime)+1:end)-app.FurnaceDelay)*1440;
                            if app.FurnTime(end)*86400<= 65 %this makes sure to update the axis
                                %if using autosyncing. It is a pretty dirty
                                %solution, but it work.
                                app.UScaleChanged = true;
                                app.CScaleChanged = true;
                            end
                        end
                        %bubbler heater plotting
                        if (app.NbTempTime(end)-app.NbTempTime(1))*1440 >240 || (app.SnTempTime(end)-app.SnTempTime(1))*1440 >240
                            appendedNbTime = (app.NbTempTime(length(app.ScaledNbTempTime)+1:end))*24;
                            appendedSnTime = (app.SnTempTime(length(app.ScaledSnTempTime)+1:end))*24;
                        elseif (app.NbTempTime(end)-app.NbTempTime(1))*1440 < 1 || (app.SnTempTime(end)-app.SnTempTime(1))*1440 < 1
                            appendedNbTime = (app.NbTempTime(length(app.ScaledNbTempTime)+1:end))*86400;
                            appendedSnTime = (app.SnTempTime(length(app.ScaledSnTempTime)+1:end))*86400;
                        else
                            appendedNbTime = (app.NbTempTime(length(app.ScaledNbTempTime)+1:end))*1440;
                            appendedSnTime = (app.SnTempTime(length(app.ScaledSnTempTime)+1:end))*1440;
                        end
                    end
                    %this is the end of the elasped time nested ifs
                    
                    %now for the actual time adjustment if needed
                    if strcmp(app.DisplaySettingsSwitch.Value, 'Actual Time')
                        appendedFurnTime = datetime(app.FurnTime(length(app.ScaledFurnTime)+1:end)+(app.Matlab_iTools_TimeDif+app.FurnStart), 'ConvertFrom', 'datenum');
                        appendedNbTime = datetime(app.TZero + app.NbTempTime(length(app.ScaledNbTempTime)+1:end), 'ConvertFrom', 'datenum');
                        appendedSnTime = datetime(app.TZero + app.SnTempTime(length(app.ScaledSnTempTime)+1:end), 'ConvertFrom', 'datenum');
                        if strcmp(app.Switch.Value, 'Custom Time') && app.CustomControl.DisplayLastTime.Value
                            switch app.CustomControl.DropDown.Value 
                                case 'min'
                                    try
                                        futLims = [datetime(app.Matlab_iTools_TimeDif+app.FurnStart + futLims(1)/1440+app.FurnaceDelay, 'ConvertFrom', 'datenum'), datetime(app.Matlab_iTools_TimeDif+app.FurnStart + futLims(2)/1440+app.FurnaceDelay, 'ConvertFrom', 'datenum')];
                                    catch
                                        futLims = app.UIAxes.XLim;
                                        %this will prevent errors when the
                                        %appenedfurntime is empty
                                    end
                                    
                                    try
                                        nbFutLims = [datetime(app.TZero + nbFutLims(1)/1440, 'ConvertFrom', 'datenum'), datetime(app.TZero + nbFutLims(2)/1440, 'ConvertFrom', 'datenum')];
                                    catch
                                        nbFutLims = app.UIAxes_3.XLim;
                                    end
                                    
                                    try
                                        snFutLims = [datetime(app.TZero + snFutLims(1)/1440, 'ConvertFrom', 'datenum'), datetime(app.TZero + snFutLims(2)/1440, 'ConvertFrom', 'datenum')];
                                    catch
                                        snFutLims = app.UIAxes_3.XLim;
                                    end
                                case 'hr'
                                    try
                                        futLims = [datetime(app.Matlab_iTools_TimeDif+app.FurnStart + futLims(1)/24+app.FurnaceDelay,'ConvertFrom', 'datenum'), datetime(app.Matlab_iTools_TimeDif+app.FurnStart + futLims(2)/24+app.FurnaceDelay, 'ConvertFrom', 'datenum')];
                                    catch
                                        futLims = app.UIAxes.XLim;
                                    end
                                    
                                    try
                                        nbFutLims = [datetime(app.TZero + nbFutLims(1)/24, 'ConvertFrom', 'datenum'), datetime(app.TZero + nbFutLims(2)/24, 'ConvertFrom', 'datenum')];
                                    catch
                                        nbFutLims = app.UIAxes_3.XLim;
                                    end
                                    
                                    try
                                        snFutLims = [datetime(app.TZero + snFutLims(1)/24, 'ConvertFrom', 'datenum'), datetime(app.TZero + snFutLims(2)/24, 'ConvertFrom', 'datenum')];
                                    catch
                                        snFutLims = app.UIAxes_3.XLim;
                                    end
                                case 'sec'
                                    try
                                        futLims = [datetime(app.Matlab_iTools_TimeDif+app.FurnStart+futLims(1)/86400+app.FurnaceDelay, 'ConvertFrom', 'datenum'), datetime(app.Matlab_iTools_TimeDif+app.FurnStart+ futLims(2)/86400+app.FurnaceDelay, 'ConvertFrom', 'datenum')];
                                    catch
                                        futLims = app.UIAxes.XLim;
                                    end
                                    
                                    try
                                        nbFutLims = [datetime(app.TZero + nbFutLims(1)/86400, 'ConvertFrom', 'datenum'), datetime(app.TZero + nbFutLims(2)/86400, 'ConvertFrom', 'datenum')];
                                    catch
                                        nbFutLims = app.UIAxes_3.XLim;
                                    end
                                    
                                    try
                                        snFutLims = [datetime(app.TZero + snFutLims(1)/86400, 'ConvertFrom', 'datenum'), datetime(app.TZero + snFutLims(2)/86400, 'ConvertFrom', 'datenum')];
                                    catch
                                        snFutLims = app.UIAxes_3.XLim;
                                    end
                            end
                        end
                    end
                    %those if and switches are pretty much identical to
                    %rescaling version, with a few catches for when stuff
                    %is empty. Now we can add the new scaled points and
                    %keep plotting
                    app.ScaledFurnTime = [app.ScaledFurnTime;appendedFurnTime];
                    app.ScaledNbTempTime = [app.ScaledNbTempTime;appendedNbTime];
                    app.ScaledSnTempTime = [app.ScaledSnTempTime;appendedSnTime];
                catch
                    Update_Log(app,'Uncontrolled hardware appending error')
                end
                
                try
                    if strcmp(app.Switch.Value, 'Custom Time')
                        if app.CustomControl.DisplayLastTime.Value
                            if nbFutLims(1) <= snFutLims(1)
                                bubLims = nbFutLims(1);
                            else
                                bubLims = snFutLims(1);
                            end
                            if nbFutLims(2) >= snFutLims(2)
                                bubLims = [bubLims, nbFutLims(2)];
                            else
                                bubLims = [bubLims, snFutLims(2)];
                            end
                        end
                        app.UIAxes.XLim = futLims;
                        app.UIAxes_3.XLim = bubLims;
                    end
                    plot(app.UIAxes,app.ScaledFurnTime,app.Z1Temps);
                    hold(app.UIAxes, 'on')
                    plot(app.UIAxes,app.ScaledFurnTime,app.Z2Temps);
                    plot(app.UIAxes,app.ScaledFurnTime,app.Z3Temps);
                    hold(app.UIAxes, 'off')
                    legend(app.UIAxes,'Zone 1','Zone 2','Zone 3','location','northwest')
                    
                    plot(app.UIAxes_3,app.ScaledNbTempTime,app.NbTemp)
                    hold(app.UIAxes_3, 'on')
                    plot(app.UIAxes_3,app.ScaledSnTempTime,app.SnTemp)
                    hold(app.UIAxes_3, 'off')
                    legend(app.UIAxes_3,'NbCl5','SnCl2','location','northwest')
                catch ME
                    ME
                    Update_Log(app,'Uncontrolled Hardware Plotting Error')
                end
            end
            %hopefully this reduces the scaling time significantly.
            %Rescaling tens or hundreds of thousands of points each time
            %seems ridulous, so hopefully storing them in memory speeds up
            %the process.
        end
        
        function Rescale_CData(app)
            try
                %this has the exact same structure as rescaleUCdata
                if strcmp(app.Switch.Value, 'Custom Time')
                    switch app.CustomControl.DropDown.Value 
                        case 'min'
                            app.ScaledTime = app.Times*1440;
                            app.ScaledNbSetFlowTime = app.NbSetFlowTime;
                            app.ScaledSnSetFlowTime = app.SnSetFlowTime;
                            app.ScaledArSetFlowTime = app.ArSetFlowTime;
                            app.UIAxes_2.XLabel.String = 'Time (min)';
                            app.UIAxes_4.XLabel.String = 'Time (min)';
                            if app.CustomControl.DisplayLastTime.Value
                                futLims = [app.ScaledTime(end)-app.CustomControl.DisplayTimeEditField.Value,app.ScaledTime(end)];
                            else
                                switch app.CustomControl.DropDown_2.Value
                                    case 'min'
                                        futLims =[app.CustomControl.StartingTimeSpinner.Value,app.CustomControl.StartingTimeSpinner.Value+app.CustomControl.DisplayTimeEditField.Value];
                                    case 'hr'
                                        futLims =[60*app.CustomControl.StartingTimeSpinner.Value,60*app.CustomControl.StartingTimeSpinner.Value+app.CustomControl.DisplayTimeEditField.Value];
                                    case 'sec'
                                        futLims =[app.CustomControl.StartingTimeSpinner.Value/60,app.CustomControl.StartingTimeSpinner.Value/60+app.CustomControl.DisplayTimeEditField.Value];
                                end
                            end
                        
                        case 'hr'
                            app.ScaledTime = app.Times*24;
                            app.ScaledNbSetFlowTime = app.NbSetFlowTime/60;
                            app.ScaledSnSetFlowTime = app.SnSetFlowTime/60;
                            app.ScaledArSetFlowTime = app.ArSetFlowTime/60;
                            app.UIAxes_2.XLabel.String = 'Time (hr)';
                            app.UIAxes_4.XLabel.String = 'Time (hr)';
                            if app.CustomControl.DisplayLastTime.Value
                                futLims = [app.ScaledTime(end)-app.CustomControl.DisplayTimeEditField.Value,app.ScaledTime(end)];
                            else
                                switch app.CustomControl.DropDown_2.Value
                                    case 'hr'
                                        futLims =[app.CustomControl.StartingTimeSpinner.Value,app.CustomControl.StartingTimeSpinner.Value+app.CustomControl.DisplayTimeEditField.Value];
                                    case 'min'
                                        futLims =[app.CustomControl.StartingTimeSpinner.Value/60,app.CustomControl.StartingTimeSpinner.Value/60+app.CustomControl.DisplayTimeEditField.Value];
                                    case 'sec'
                                        futLims =[app.CustomControl.StartingTimeSpinner.Value/3600,app.CustomControl.StartingTimeSpinner.Value/3600+app.CustomControl.DisplayTimeEditField.Value];
                                end
                            end
                        
                        case 'sec'
                            app.ScaledTime = app.Times*86400;
                            app.ScaledNbSetFlowTime = app.NbSetFlowTime*60;
                            app.ScaledSnSetFlowTime = app.SnSetFlowTime*60;
                            app.ScaledArSetFlowTime = app.ArSetFlowTime*60;
                            app.UIAxes_2.XLabel.String = 'Time (sec)';
                            app.UIAxes_4.XLabel.String = 'Time (sec)';
                            if app.CustomControl.DisplayLastTime.Value
                                futLims = [app.ScaledTime(end)-app.CustomControl.DisplayTimeEditField.Value,app.ScaledTime(end)];
                            else
                                switch app.CustomControl.DropDown_2.Value
                                    case 'sec'
                                        futLims =[app.CustomControl.StartingTimeSpinner.Value,app.CustomControl.StartingTimeSpinner.Value+app.CustomControl.DisplayTimeEditField.Value];
                                    case'min'
                                        futLims =[60*app.CustomControl.StartingTimeSpinner.Value,60*app.CustomControl.StartingTimeSpinner.Value+app.CustomControl.DisplayTimeEditField.Value];
                                    case 'hr'
                                        futLims =[app.CustomControl.StartingTimeSpinner.Value*3600,app.CustomControl.StartingTimeSpinner.Value*3600+app.CustomControl.DisplayTimeEditField.Value];
                                end
                            end
                    end
                    
                else %switch value is all time
                    if app.Times(end)*1440 >240%minutes
                        app.ScaledTime = app.Times*24;
                        app.ScaledNbSetFlowTime = app.NbSetFlowTime/60;
                        app.ScaledSnSetFlowTime = app.SnSetFlowTime/60;
                        app.ScaledArSetFlowTime = app.ArSetFlowTime/60;
                        app.UIAxes_2.XLabel.String = 'Time (hr)';
                        app.UIAxes_4.XLabel.String = 'Time (hr)';
                    elseif app.Times(end)*1440 < 1%minute
                        app.ScaledTime = app.Times*86400;
                        app.ScaledNbSetFlowTime = app.NbSetFlowTime*60;
                        app.ScaledSnSetFlowTime = app.SnSetFlowTime*60;
                        app.ScaledArSetFlowTime = app.ArSetFlowTime*60;
                        app.UIAxes_2.XLabel.String = 'Time (sec)';
                        app.UIAxes_4.XLabel.String = 'Time (sec)';
                    else
                        app.ScaledTime = app.Times*1440;
                        app.ScaledNbSetFlowTime = app.NbSetFlowTime;
                        app.ScaledSnSetFlowTime = app.SnSetFlowTime;
                        app.ScaledArSetFlowTime = app.ArSetFlowTime;
                        app.UIAxes_2.XLabel.String = 'Time (min)';
                        app.UIAxes_4.XLabel.String = 'Time (min)';
                    end
                end
                %this is the end of the elasped time nested ifs
                
                %now for the actual time adjustment if needed
                if strcmp(app.DisplaySettingsSwitch.Value, 'Actual Time')
                    app.UIAxes_2.XLabel.String = 'Time';
                    app.UIAxes_4.XLabel.String = 'Time';
                    app.ScaledTime = datetime(app.Times+app.TZero, 'ConvertFrom', 'datenum');
                    app.ScaledNbSetFlowTime = datetime(app.NbSetFlowTime/1440+app.TZero, 'ConvertFrom', 'datenum');
                    app.ScaledSnSetFlowTime = datetime(app.SnSetFlowTime/1440+app.TZero, 'ConvertFrom', 'datenum');
                    app.ScaledArSetFlowTime = datetime(app.ArSetFlowTime/1440+app.TZero, 'ConvertFrom', 'datenum');
                    if strcmp(app.Switch.Value, 'Custom Time')
                        switch app.CustomControl.DropDown.Value 
                            case 'min'
                                futLims = [datetime(app.TZero + futLims(1)/1440, 'ConvertFrom', 'datenum'), datetime(app.TZero + futLims(2)/1440, 'ConvertFrom', 'datenum')];
                            case 'hr'
                                futLims = [datetime(app.TZero + futLims(1)/24,'ConvertFrom', 'datenum'), datetime(app.TZero + futLims(2)/24, 'ConvertFrom', 'datenum')];
                            case 'sec'
                                futLims = [datetime(app.TZero +futLims(1)/86400, 'ConvertFrom', 'datenum'), datetime(app.TZero + futLims(2)/86400, 'ConvertFrom', 'datenum')];
                        end
                    end
                end
            catch ME
                ME
                Update_Log(app, 'Controlled hardware rescaling error')
            end
            try
                if app.DisplayMeasuredPressureCheckBox.Value
                    plot(app.UIAxes_2,app.ScaledTime,app.Pres);
                    legend(app.UIAxes_2,'Measured','location','northwest')
                else
                    plot(app.UIAxes_2,app.ScaledTime(1),0);
                    %this will reset the x axis and prevent errors from the
                    %x axis being differnet data types when setting futlims
                end
                plot(app.UIAxes_4,app.ScaledNbSetFlowTime,app.NbSetFlow)
                hold(app.UIAxes_4, 'on')
                plot(app.UIAxes_4,app.ScaledSnSetFlowTime,app.SnSetFlow);
                plot(app.UIAxes_4,app.ScaledArSetFlowTime,app.ArSetFlow);
                hold(app.UIAxes_4, 'off')
                legend(app.UIAxes_4,'NbCl5','SnCl2','Ar','location','northwest')
                if not(isempty(app.ArFlow))%if one empty, they all empty
                    hold(app.UIAxes_4, 'on')
                    plot(app.UIAxes_4,app.Times,app.ArFlow);
                    plot(app.UIAxes_4,app.Times,app.NbFlow);
                    plot(app.UIAxes_4,app.Times,app.SnFlow);
                    legend(app.UIAxes_4,'Set NbCl5','Set SnCl2','Set Ar','Measured NbCl5','Measured SnCl2','Measured Ar','location','northwest')
                    hold(app.UIAxes_4, 'off')
                end
            
                %These next ifs do two things. They add the set pressure
                %plotting and apply the x axis formatting.
                if strcmp(app.Switch.Value, 'All Time')
                    app.UIAxes_2.XLimMode = 'auto';%this adjusts to correct size
                    app.UIAxes_4.XLim = app.UIAxes_2.XLim;
                    app.UIAxes_2.XLim = app.UIAxes_4.XLim;%this turns of the auto mode, 
                    % so future plotting will not change the x axes
                    if app.DisplaySetPressureCheckBox.Value
                        if strcmp(app.DisplaySettingsSwitch.Value, 'Elasped Time')
                            switch app.UIAxes_2.XLabel.String(7)
                                case 'h'
                                    scaledSetPresTime = app.SetPresTime/60;
                                case 'm'
                                    scaledSetPresTime = app.SetPresTime;
                                case 's'
                                    scaledSetPresTime = app.SetPresTime*60;
                            end
                        else
                            scaledSetPresTime = datetime(app.TZero + app.SetPresTime/1440, 'ConvertFrom', 'datenum');
                        end
                        if app.DisplayMeasuredPressureCheckBox.Value
                            hold(app.UIAxes_2, 'on')
                        end
                        plot(app.UIAxes_2,scaledSetPresTime,app.SetPressures)
                        
                        if app.DisplayMeasuredPressureCheckBox.Value
                            hold(app.UIAxes_2,'off')
                            legend(app.UIAxes_2,'Measured','Set','location','northwest')
                        else
                            legend(app.UIAxes_2,'Set','location','northwest')
                            app.UIAxes_2.XLimMode = 'auto';
                        end
                        %note, you must be careful with the holding and the
                        %legends when changing what is displayed in UIAxes2
                        
                        %note, these alltime plots will not always be
                        %in auto mode
                    end
                    %now the set pressure is plotted
                else
                    if app.DisplaySetPressureCheckBox.Value
                        if strcmp(app.DisplaySettingsSwitch.Value, 'Elasped Time')
                            switch app.UIAxes_2.XLabel.String(7)
                                case 'h'
                                    scaledSetPresTime = app.SetPresTime/60;
                                case 'm'
                                    scaledSetPresTime = app.SetPresTime;
                                case 's'
                                    scaledSetPresTime = app.SetPresTime*60;
                            end
                        else
                            scaledSetPresTime = datetime(app.TZero + app.SetPresTime/1440, 'ConvertFrom', 'datenum');
                        end
                        if app.DisplayMeasuredPressureCheckBox.Value
                            hold(app.UIAxes_2, 'on')
                        end
                        plot(app.UIAxes_2,scaledSetPresTime,app.SetPressures)
                        
                        if app.DisplayMeasuredPressureCheckBox.Value
                            hold(app.UIAxes_2,'off')
                            legend(app.UIAxes_2,'Measured','Set','location','northwest')
                        else
                            legend(app.UIAxes_2,'Set','location','northwest')
                        end
                    end
                    if app.DisplayMeasuredPressureCheckBox.Value || app.DisplaySetPressureCheckBox.Value
                        app.UIAxes_2.XLim = futLims;
                        app.UIAxes_4.XLim = futLims;
                    end
                end
                
            catch ME
                ME
                Update_Log(app,'Controlled hardware potting error')
            end
        end
        
        function Append_CData(app)
            %this fucntion is slightly different structure than the other
            %three because actual time correction is built into both custom
            %and all time branches, so it is slightly depper, but same
            %gerenal vibes
            try
                if strcmp(app.Switch.Value, 'Custom Time')
                    if  strcmp(app.DisplaySettingsSwitch.Value, 'Actual Time')
                        app.ScaledTime = [app.ScaledTime;datetime(app.Times(end)+app.TZero, 'ConvertFrom', 'datenum')];
                        switch app.CustomControl.DropDown.Value 
                            case 'min'
                                if app.CustomControl.DisplayLastTime.Value
                                    futLims = [datetime(datenum(app.ScaledTime(end))-app.CustomControl.DisplayTimeEditField.Value/1440, 'ConvertFrom', 'datenum'),app.ScaledTime(end)];
                                else
                                    futLims = app.UIAxes_2.XLim;
                                end
                            case 'hr'
                                if app.CustomControl.DisplayLastTime.Value
                                    futLims = [datetime(datenum(app.ScaledTime(end))-app.CustomControl.DisplayTimeEditField.Value/24, 'ConvertFrom', 'datenum'),app.ScaledTime(end)];
                                else
                                    futLims = app.UIAxes_2.XLim;
                                end
                            case 'sec'
                                if app.CustomControl.DisplayLastTime.Value
                                    futLims = [datetime(datenum(app.ScaledTime(end))-app.CustomControl.DisplayTimeEditField.Value/86400, 'ConvertFrom', 'datenum'),app.ScaledTime(end)];
                                else
                                    futLims = app.UIAxes_2.XLim;
                                end
                        end
                    else
                        switch app.CustomControl.DropDown.Value 
                            case 'min'
                                app.ScaledTime = [app.ScaledTime; app.Times(end)*1440];
                                if app.CustomControl.DisplayLastTime.Value
                                    futLims = [app.ScaledTime(end)-app.CustomControl.DisplayTimeEditField.Value,app.ScaledTime(end)];
                                else
                                    futLims = app.UIAxes_2.XLim;
                                end
                            case 'hr'
                                app.ScaledTime = [app.ScaledTime; app.Times(end)*24];
                                if app.CustomControl.DisplayLastTime.Value
                                    futLims = [app.ScaledTime(end)-app.CustomControl.DisplayTimeEditField.Value,app.ScaledTime(end)];
                                else
                                    futLims = app.UIAxes_2.XLim;
                                end
                            case 'sec'
                                app.ScaledTime = [app.ScaledTime; app.Times(end)*86400];
                                if app.CustomControl.DisplayLastTime.Value
                                    futLims = [app.ScaledTime(end)-app.CustomControl.DisplayTimeEditField.Value,app.ScaledTime(end)];
                                else
                                    futLims = app.UIAxes_2.XLim;
                                end
                        end
                    end
                    
                else %switch value is all time
                    if strcmp(app.DisplaySettingsSwitch.Value, 'Actual Time')
                        app.ScaledTime = [app.ScaledTime;datetime(app.Times(end)+app.TZero, 'ConvertFrom', 'datenum')];
                    else
                        if strcmp(app.DisplaySettingsSwitch.Value, 'Elasped Time') && app.Times(end)*1440 >240%minutes
                            if app.UIAxes_2.XLabel.String(7) ~= 'h'
                                error('Change Detected')
                            end
                            app.ScaledTime = [app.ScaledTime; app.Times(end)*24];
                        elseif app.Times(end)*1440 < 1%minute
                            if strcmp(app.DisplaySettingsSwitch.Value, 'Elasped Time') && app.UIAxes_2.XLabel.String(7) ~= 's'
                                error('Change Detected')
                            end
                            app.ScaledTime = [app.ScaledTime; app.Times(end)*86400];
                        else
                            if strcmp(app.DisplaySettingsSwitch.Value, 'Elasped Time') && app.UIAxes_2.XLabel.String(7) ~= 'm'
                                error('Change Detected')
                            end
                            app.ScaledTime = [app.ScaledTime; app.Times(end)*1440];
                        end
                    end
                end
                %this is the end of the elasped time nested ifs
                waserr = false;
               
            catch ME
                switch ME.message
                    case 'Change Detected'
                        waserr = true;
                        app.UScaleChanged = true;
                        app.CScaleChanged = true;
                        %this will work for both C and UC
                    otherwise
                        ME.message
                        Update_Log(app, 'Controlled hardware rescaling error')
                end
            end
            try
                %I put the axis settings first in the appending because I
                %am not worried about conflicting units (datetime versus
                %elasped time) this will hopefully allow matlab to graph
                %fewer total points and increase speed
                if not(waserr)
                    if strcmp(app.Switch.Value, 'Custom Time')
                        app.UIAxes_2.XLim = futLims;
                        app.UIAxes_4.XLim = futLims;
                    else
                        app.UIAxes_2.XLimMode = 'auto';
                    end
                    if app.DisplayMeasuredPressureCheckBox.Value
                        plot(app.UIAxes_2,app.ScaledTime,app.Pres);
                    end
                    plot(app.UIAxes_4,app.ScaledNbSetFlowTime,app.NbSetFlow)
                    hold(app.UIAxes_4, 'on')
                    plot(app.UIAxes_4,app.ScaledSnSetFlowTime,app.SnSetFlow);
                    plot(app.UIAxes_4,app.ScaledArSetFlowTime,app.ArSetFlow);
                    hold(app.UIAxes_4, 'off')
                    legend(app.UIAxes_4,'NbCl5','SnCl2','Ar','location','northwest')
                    if not(isempty(app.ArFlow))%if one empty, they all empty
                        hold(app.UIAxes_4, 'on')
                        plot(app.UIAxes_4,app.Times,app.ArFlow);
                        plot(app.UIAxes_4,app.Times,app.NbFlow);
                        plot(app.UIAxes_4,app.Times,app.SnFlow);
                        legend(app.UIAxes_4,'Set NbCl5','Set SnCl2','Set Ar','Measured NbCl5','Measured SnCl2','Measured Ar','location','northwest')
                        hold(app.UIAxes_4, 'off')
                    end
                    
                    if app.DisplaySetPressureCheckBox.Value
                        if strcmp(app.DisplaySettingsSwitch.Value, 'Elasped Time')
                            switch app.UIAxes_2.XLabel.String(7)
                                case 'h'
                                    scaledSetPresTime = app.SetPresTime/60;
                                case 'm'
                                    scaledSetPresTime = app.SetPresTime;
                                case 's'
                                    scaledSetPresTime = app.SetPresTime*60;
                            end
                        else
                            scaledSetPresTime = datetime(app.TZero + app.SetPresTime/1440, 'ConvertFrom', 'datenum');
                        end
                        if strcmp(app.Switch.Value, 'All Time')
                            app.UIAxes_4.XLim = app.UIAxes_2.XLim;
                            app.UIAxes_2.XLim = app.UIAxes_4.XLim;%locking in axis
                        end
                        if app.DisplayMeasuredPressureCheckBox.Value
                            hold(app.UIAxes_2, 'on')
                        end
                        plot(app.UIAxes_2,scaledSetPresTime,app.SetPressures)
                        hold(app.UIAxes_2,'off')
                        if app.DisplayMeasuredPressureCheckBox.Value
                            legend(app.UIAxes_2,'Measured','Set','location','northwest')
                        else
                            legend(app.UIAxes_2,'Set','location','northwest')
                        end
                        %now the set pressure is plotted
                    elseif app.DisplayMeasuredPressureCheckBox.Value
                        legend(app.UIAxes_2,'Measured','location','northwest')                        
                    else
                        legend(app.UIAxes_2,'hide')
                    end
                end
            catch ME
                ME
                Update_Log(app,'Controlled hardware potting error')
            end
        end
        
        function FailSafe(app)
            %This ensures that the whole system turns out and ends in a
            %safe state

            %this is a little bit of an antiquated fucntion. What I mean by
            %that is the program is not designed to shut off all
            %fucnitonalltiy like I intially thought. this fucntion is
            %almost always skipped, but I leave it here in case of future
            %desires to use something similar.
            if app.PressureTorrEditField.Value > app.PressureTorrEditField_2.Value || app.Z1ActTemp > app.FurnaceTempCEditField.Value || app.Z2ActTemp > app.FurnaceTempCEditField.Value || app.Z3ActTemp > app.FurnaceTempCEditField.Value
                Update_Log(app,'Initiating Failsafe')
                Change_Status(app,'Critical')   
                app.NbActFlow = 0;
                app.SnActFlow = 0;
                app.HActFlow = 0;
                app.ArActFlow = 1;
                try
                    QueryValve(app,'O:');
                catch
                    Update_Log(app,'Could not open Valve')
                end
                %*insert other protective measures*
                while app.PressureTorrEditField.Value > app.PressureTorrEditField_2.Value || app.Z1ActTemp > app.FurnaceTempCEditField.Value || app.Z2ActTemp > app.FurnaceTempCEditField.Value || app.Z3ActTemp > app.FurnaceTempCEditField.Value
                    Update_Data(app)
                    pause(app.PauseLength)
                end
                Update_Log(app,'Flushing complete. Turning Off furnace')
            end
            if not(isempty(app.Times)) && app.Times(end) > .1
                formatcreating(app)
            end
            
            %this part is not skipped however:
            %now we will save all the output data
            %first furnace
            readFileName = ['Data Files/Furnace Data Files/', app.FurnaceDataFileDropDown.Value];
            copiedFileName = ['Backup Files/Copyof',app.FurnaceDataFileDropDown.Value];
            copyfile(readFileName, copiedFileName)
            %now pressure
            writetable(table(app.Times+app.TZero,app.Pres,'VariableNames',{'Time (MATLAB Date Num)','Pressure (Torr)'}),['Data Files/Pressure Files/Measured Pressure/',app.TimeStarted,'.csv'])
            if not(isempty(app.ArFlow))
                writetable(table(app.Times+app.TZero,app.ArFlow,'VariableNames',{'Time (MATLAB Date Num)','Flow Rate (sccm?)'}),['Data Files/Mass Flow Files/Ar',app.TimeStarted,'.csv'])
                writetable(table(app.Times+app.TZero,app.NbFlow,'VariableNames',{'Time (MATLAB Date Num)','Flow Rate (sccm?)'}),['Data Files/Mass Flow Files/Nb',app.TimeStarted,'.csv'])
                writetable(table(app.Times+app.TZero,app.SnFlow,'VariableNames',{'Time (MATLAB Date Num)','Flow Rate (sccm?)'}),['Data Files/Mass Flow Files/Sn',app.TimeStarted,'.csv'])
            end
            Change_Status(app,'Off')
        end
        
        function SyncTime(app)
            %this is entirely for the automatic furnace syncing
            syncerror = false;
            stable = false;
            app.SyncStatusLamp.Color = [1.00,0.41,0.16];%orange
            app.NotSyncedLabel.Text = 'Waiting for Stablization';
            while not(app.IsSynced) && not(syncerror)
                %this is the loop that waits for syncing
                Update_Data(app)
                avgtemp = (app.Z1ActTemp+app.Z2ActTemp+app.Z3ActTemp)/3;
                if avgtemp >= app.DegassingTempCEditField.Value + app.TempTol && strcmp(app.ProgramStatusEditField.Value,'Active')
                    Update_Log(app,'Attempting to read ramp')
                    app.SyncStatusLamp.Color = 'y';
                    app.NotSyncedLabel.Text = 'Syncing';
                    synctimes = app.FurnTime(end);
                    synctemps = avgtemp;
                    Update_Data(app)
                    while 1440*(app.FurnTime(end)-synctimes(1)) < 5%minutes
                        synctimes = [synctimes,app.FurnTime(end)];
                        synctemps = [synctemps,avgtemp];
                        Update_Data(app)
                        matlabtime = now;
                        avgtemp = (app.Z1ActTemp+app.Z2ActTemp+app.Z3ActTemp)/3;
                        pause(app.PauseLength)
                    end
                    fit = polyfit(synctimes,synctemps,1);
                    slope = fit(1);%in units of celcius/24hr
                    yint = fit(2);
                    itoolsguess = (50-yint)/slope;
                    app.TZero = matlabtime - (synctimes(end)-itoolsguess);%this is the 
                    % time that we wil treat as zero (in units of matlab time)
                    app.FurnaceDelay = itoolsguess - app.FurnTime(1);%this is the how long
                    %ago the furnace began recording in days
                    Update_Log(app,['Synced zeropoint at ',datestr(app.TZero)])
                    app.IsSynced = true;
                    app.UScaleChanged = true;
                    app.CScaleChanged = true;
                    if stable
                        Update_Log(app,'Successfully Synced')
                    else
                        Update_Log(app,'Did not reach stablility. Sync may be inaccurate')
                    end
                    app.SyncStatusLamp.Color = 'g';
                    app.NotSyncedLabel.Text = 'Synced';
                
                elseif avgtemp <= app.DegassingTempCEditField.Value - app.TempTol
                    Update_Log(app,'Fell below Degassing Range. Recheck Parameters')
                    app.SyncStatusLamp.Color = 'r';
                    app.NotSyncedLabel.Text = 'Not Synced';
                    syncerror = true;
                end
                if length(app.Z1Temps) > 50 && not(stable) && not(app.IsSynced)% a temp will be added to list at about 
                    %rate of 1 temp per 2.5 seconds so 100 is about 2 minutes
                    
                    %this is how the furance determines stability. It is
                    %not required, but will update log with a warning if it
                    %is not stable. Note .5 is arbitary.
                    stable = true;
                    for i = length(app.Z1Temps)-50:length(app.Z1Temps)
                        if abs((app.Z1Temps(i)+app.Z2Temps(i)+app.Z3Temps(i))/3-app.DegassingTempCEditField.Value) > .5
                            stable = false;
                            break
                        end
                    end
                    %to prevent weird effects when exiting, I want to make
                    %sure it can only change the colors when the program is
                    %running
                    if stable && strcmp(app.ProgramStatusEditField.Value,'Active')
                        app.SyncStatusLamp.Color = 'y';
                        app.NotSyncedLabel.Text = 'Stable';
                    elseif strcmp(app.ProgramStatusEditField.Value,'Active') && not(syncerror)
                        app.SyncStatusLamp.Color = [1.00,0.41,0.16];%orange
                        app.NotSyncedLabel.Text = 'Waiting for Stablization';
                    end
                end
                pause(app.PauseLength)
            end
        end
        
        function val = QueryValve(app,com)
            resp = query(app.ValveSerial, com, '%s\n' ,'%s');
            if not(isempty(resp))
                switch resp(1)
                    case 'P'
                        %the first two characters of resp are always 'P:'
                        %The third character is 0 for pos and - for neg ints
                        %the remaining 7 characters represent the measured
                        %pressure. These values are scaled. There are two
                        %things that effect this scaling: full scale pressure
                        %and communication range. Full scale presure is the max
                        %pressure value that the sensor can  output, in our case
                        %999 torr. The communication range is the amount of
                        %parts that the range is divided into. this controlled
                        %in app.PresRes, and we must add 1, because pwe must include 0 
                        if resp(3) == '0'
                            val = str2double(resp(4:10))*999/(str2double(app.PresRes)+1);
                        else
                            val = str2double(resp(4:10))*-999/(str2double(app.PresRes)+1);
                        end
                    case 'E'
                        %The error codes can be found in the butterfly manual
                        %page 83/117
                        Update_Log(app,['Error from butterfly valve: ',resp])
                    case 'A'
                        %This outputs posistion on a linear scale where 0 is
                        %closed and 1000 is fully open
                        val = str2double(resp(5:8))/10;
                    otherwise
                        val = resp;
                end
            else
                val = 'empty';%this is just a random string that isnt O: etc
            end
        end
        
        function setpres = SetPres(app)
            %if you want more info on this command, it is described on page
            %63/117 of the buterfly valve manual, as well as in the
            %pressure part of the QueryValve function
            currtime = 1440*app.Times(end);%here we convert to minutes
            if currtime >app.SetPresTime(end)
                QueryValve(app,['S:00',int2str(app.SetPressures(end)*(str2double(app.PresRes)+1)/999)]);
                setpres = app.SetPresTime(end);
            else
                for time = 2:length(app.SetPresTime)
                    if currtime < app.SetPresTime(time) && currtime >= app.SetPresTime(time - 1)
                        QueryValve(app,['S:00',int2str(app.SetPressures(time)*(str2double(app.PresRes)+1)/999)]);
                        setpres = app.SetPressures(time);
                        break
                    end
                end
            end
        end
        
        function formatcreating(app)
            %get formatting
            f = fileread('formatting.txt');
            % Creating formatting
            app.UIFormatting.Icon = 'olddata.gif';
            app.UIFormatting.IconAlignment = 'top';
            app.UIFormatting.BackgroundColor = [0 0 0];
            app.UIFormatting.FontSize = 20;
            app.UIFormatting.FontColor = [1 1 1];
            app.UIFormatting.Visible = 'on';
            app.UIFormatting.Position = [208 334 236 254];
            app.UIFormatting.Text = {f(1:14);f(17:37);f(40:48)};
            app.UIBackground.Icon = 'previousfuncs.gif';
            app.UIBackground.IconAlignment = 'top';
            app.UIBackground.BackgroundColor = [0 0 0];
            app.UIBackground.FontSize = 20;
            app.UIBackground.FontColor = [1 1 1];
            app.UIBackground.Visible = 'on';
            app.UIBackground.Position = [550 218 520 480];
            app.UIBackground.Text = {f(49:73);'';f(75:87);f(89:95);f(97:128)};
        end
        
        function formatcheck(app)
            %fix formatting
            app.UIBackground.BackgroundColor = [0.651 0.651 0.651];
            app.UIBackground.Visible = 'off';
            app.UIBackground.Position = [1092 -1 10 10];
            app.UIBackground.Text = '';
            app.UIFormatting.BackgroundColor = [0.651 0.651 0.651];
            app.UIFormatting.Visible = 'off';
            app.UIFormatting.Position = [1092 -1 10 10];
            app.UIFormatting.Text = '';
        end
        
        function SetMFC(app)
            %this is where we set the mass flow set points for each mfc
            %and also where we update the global variables for flow rate
            %(dont change next line, just uncomment)
            
%             currtime = 1440*app.Times(end);%here we convert to minutes
            
            %these next three ifs just check the what the set value should
            %be based on the current app.Times value for each gas
            %(dont change next lines, just uncomment)
            
%             if currtime >app.ArSetFlowTime(end)
%                 setAr = app.ArSetFlowTime(end);
%             else
%                 for time = 2:length(app.ArSetFlowTime)
%                     if currtime < app.ArSetFlowTime(time) && currtime >= app.ArSetFlowTime(time - 1)
%                         setAr = app.ArSetFlowTime(time);
%                         break
%                     end
%                 end
%             end
%             
%             if currtime >app.NbSetFlowTime(end)
%                 setNb = app.NbSetFlowTime(end);
%             else
%                 for time = 2:length(app.NbSetFlowTime)
%                     if currtime < app.NbSetFlowTime(time) && currtime >= app.NbSetFlowTime(time - 1)
%                         setNb = app.NbSetFlowTime(time);
%                         break
%                     end
%                 end
%             end
%             
%             if currtime >app.SnSetFlowTime(end)
%                 setSn = app.SnSetFlowTime(end);
%             else
%                 for time = 2:length(app.SnSetFlowTime)
%                     if currtime < app.SnSetFlowTime(time) && currtime >= app.SnSetFlowTime(time - 1)
%                         setSn = app.SnSetFlowTime(time);
%                         break
%                     end
%                 end
%             end


            %these are my guesses for how we will get the actual flows from
            %the mfc, so change this:
            
            %first send set flow to MFCC
%             query(MFCC,['setAr',int2str(setAr)])
%             query(MFCC,['setNb',int2str(setNb)])
%             query(MFCC,['setSn',int2str(setSn)])
            
            %now receive measured
%             app.ArActFlow = query(MFCC,'getAr')
%             app.NbActFlow = query(MFCC,'getNb')
%             app.SnActFlow = query(MFCC,'getSn')


            %this will keep track of all the data dont change
            %(dont change next lines, just uncomment)
%             app.ArFlow = [app.ArFlow,app.ArActFlow]
%             app.NbFlow = [app.NbFlow,app.NbActFlow]
%             app.SnFlow = [app.SnFlow,app.SnActFlow]
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.TimeStarted = strrep(datestr(now),':',';');%The colons are replaced
            %so I can use this string to name a .txt file
            try
                f = dir('Data Files/Furnace Data Files/');
                files = {};
                if length(f)<3
                    error('No furnace data files were found')
                end
                for i = 3:length(f)
                    file = f(i);
                    files(i-2) = {file.name};
                end
                app.FurnaceDataFileDropDown.Items = files;
            catch
                Update_Log(app,'No furnace data files were found')
            end
            
            try
                f = dir('Data Files/Pressure Files/Set Pressure/');
                g = dir('Data Files/Pressure Files/Measured Pressure/');
                files = {};
                if length(f)<3 && length(g)<3
                    error('No pressure files were found')
                end
                for i = 3:length(f)
                    file = f(i);
                    files(i-2) = {file.name};
                end
                for i = 3:length(g)
                    file = g(i);
                    files(i-4+length(f)) = {file.name};
                end
                app.PressureProfileDropDown.Items = files;
            catch
                Update_Log(app,'No pressure files were found')
            end
            try
                f = dir('Data Files/Bubbler Files/');
                files = {};
                if length(f)<3
                    error('No bubbler files were found')
                end
                for i = 3:length(f)
                    file = f(i);
                    if strcmp(file.name(length(file.name)-2:length(file.name)),'csv')
                        files(length(files) + 1) = {file.name};
                    end
                end
                app.BubblerFileDropDown_2.Items = files;
                app.BubblerFileDropDown.Items = files;
            catch
                Update_Log(app,'No bubbler data files were found')
            end
            try
                f = dir('Data Files/Mass Flow Files/');
                files = {};
                if length(f)<3
                    error('No mass flow files were found')
                end
                for i = 3:length(f)
                    file = f(i);
                    files(i-2) = {file.name};
                end
                app.NbCl5MFCProfileDropDown.Items = files;
                app.SnCl2MFCProfileDropDown.Items = files;
                app.ArMFCProfileDropDown.Items = files;
            catch
                Update_Log(app,'No mass flow files were found')
            end
            Update_Log(app,'System Ready')
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            YN = uiconfirm(app.UIFigure,'Do you want to close the app?', 'Close request');
            if strcmpi(YN,'OK') && (strcmp(app.ProgramStatusEditField.Value,'Off') || strcmp(app.ProgramStatusEditField.Value,'Ready'))
                Change_Status(app,'Off')
                try
                    fclose(app.ValveSerial);
                    delete(app.ValveSerial);
                    clear app.ValveSerial;
                catch
                end
                try
                    delete(app.CustomControl)
                catch 
                end
                delete(app)
                %here I make sure to turn the furnace off if the app is closed
            elseif strcmpi(YN,'OK')
                errordlg('Cannot exit program while program is active','Program Busy');
            end
        end

        % Button pushed function: CheckFuranceFileButton
        function CheckFuranceFileButtonPushed(app, event)
            try
                ChangeInterlock(app,'clear furnace')
                furnaceData = readmatrix(strcat('Data Files/Furnace Data Files/', app.FurnaceDataFileDropDown.Value));
                app.FurnStart = furnaceData(1,1);
                furnaceDatalast = (furnaceData(end,1) - app.FurnStart);
                if furnaceDatalast*1440 > 30
                    YN = uiconfirm(app.UIFigure,[app.FurnaceDataFileDropDown.Value,' contains ',int2str(furnaceDatalast*1440),' minutes of data. Overwrite?'], 'File Already contains data.');
                    if strcmpi(YN,'OK')
                        ChangeInterlock(app,'furnace')
                    end
                else
                    ChangeInterlock(app,'furnace')
                end
            catch
                if isempty(furnaceData)
                    Update_Log(app,'Furnace data file empty')
                else
                    Update_Log(app,'Error reading furnace data file')
                end
                Change_Status(app,'Off')
            end
        end

        % Button pushed function: ConnecttoButterflyValveButton
        function ConnecttoButterflyValveButtonPushed(app, event)
%             try
                ChangeInterlock(app,'clear butterfly valve')
                %this first part autodetects which com port the butterfly
                %valve is using
                %I am removing port COM3 because it is giving me issues and
                %I dont think it will change value
                ports = serialportlist;
                comport = [];
                for i = 1:length(ports)
                    app.ValveSerial = instrfind('Type', 'serial', 'Port', ports(i), 'Tag', '');
                    if isempty(app.ValveSerial)
                        app.ValveSerial = serial(ports(i));
                    else
                        fclose(app.ValveSerial);
                        app.ValveSerial = app.ValveSerial(1);
                    end
                    try
                        fopen(app.ValveSerial);
                    catch ME
                        %this probably means the valveserial connect was
                        %improperly disconnected and cleared
                        Update_Log(app,'Serial ports improperly closed. Restart matlab application')
                    end
                    set(app.ValveSerial, 'Terminator', {'CR/LF','CR/LF'});
                    set(app.ValveSerial, 'Timeout', 0.5);
                    if strcmp(QueryValve(app,'c:0101'),'c:01') && strcmp(QueryValve(app,'O:'), 'O:')
                        Update_Log(app,'Successfully connected to butterfly valve')
                        query(app.ValveSerial, ['s:210',app.PresRes], '%s\n' ,'%s');
                        comport = ports(i);
                        break
                    else
                        fclose(app.ValveSerial);
                        delete(app.ValveSerial);
                        clear app.ValveSerial;
                    end
                end
                if isempty(comport)
                    error('Communication not successful')
                end
                
                %This next try, tries to read the pressure file
                try
                    name = app.PressureProfileDropDown.Value;
                    if strcmp(name(length(name)-2:length(name)),'txt')
                        data = readmatrix(['Data Files/Pressure Files/Set Pressure/',app.PressureProfileDropDown.Value]);
                        
                        app.SetPresTime = data(:,1);
                        app.SetPressures = data(:,2);
                        Update_Log(app,['Butterfly Valve will run ',app.PressureProfileDropDown.Value])
                    elseif strcmp(name(length(name)-2:length(name)),'csv')
                        data = readmatrix(['Data Files/Pressure Files/Measured Pressure/',app.PressureProfileDropDown.Value]);
                        firstTime = data(1,1);
                        if isnan(firstTime)
                            Update_Log(app,[app.PressureProfileDropDown.Value,' is empty'])
                            error([app.PressureProfileDropDown.Value,' is empty'])
                        end
                        Update_Log(app,['Will start pressure data that began ',datestr(firstTime)])
                        app.SetPresTime = 1440*(data(:,1)-firstTime);
                        app.SetPressures = data(:,2);
                    else
                        error('Unable to read pressure file')
                    end
                    
                    ChangeInterlock(app,'butterfly valve')
                catch
                    Update_Log(app,'Unable to read pressure file')
                end
%             catch
%                 Update_Log(app,'Valve Communication Falied')
%             end
        end

        % Button pushed function: PreviewPressureProfileButton
        function PreviewPressureProfileButtonPushed(app, event)
            try
                PrevPres(app.PressureProfileDropDown.Value)
            catch
                Update_Log(app,'Unable to preview pressure file')
            end
        end

        % Button pushed function: CreatePressureProfileButton
        function CreatePressureProfileButtonPushed(app, event)
            CreatePres(app);
        end

        % Button pushed function: CheckHeaterFilesButton
        function CheckHeaterFilesButtonPushed(app, event)
            try
                ChangeInterlock(app,'clear bubbler heaters')
                NbbubData = readtable(strcat('Data Files/Bubbler Files/', app.BubblerFileDropDown.Value));
                SnbubData = readtable(strcat('Data Files/Bubbler Files/', app.BubblerFileDropDown_2.Value));
                Nbtime = datetime('today') + table2array(NbbubData(:,1));
                Sntime = datetime('today') + table2array(SnbubData(:,1));
                app.NbBubStart = datenum(Nbtime(1));
                app.SnBubStart = datenum(Sntime(1));
                Nblast = datenum(Nbtime(end))-app.NbBubStart;
                Snlast = datenum(Sntime(end))-app.SnBubStart;
                if Nblast*1440 > 30 || Snlast*1440 > 30%longer than 30 minutes
                    YN = uiconfirm(app.UIFigure,[app.BubblerFileDropDown.Value,' contains ',int2str(Nblast*1440),' minutes of data, and ',app.BubblerFileDropDown_2.Value,' contains ',int2str(Snlast*1440),' minutes of data. Overwrite?'], 'File Already contains data.');
                    if strcmpi(YN,'OK')
                        ChangeInterlock(app,'bubbler heaters')
                    end
                else
                    ChangeInterlock(app,'bubbler heaters')
                end
            catch
                if isempty(NbbubData) && isempty(SnbubData)
                    Update_Log(app,'Both Bubbler heater data files are empty')
                elseif isempty(NbbubData)
                    Update_Log(app,[app.BubblerFileDropDown.Value,' is empty'])
                elseif isempty(SnbubData)
                    Update_Log(app,[app.BubblerFileDropDown_2.Value,' is empty'])
                else
                    Update_Log(app,'Error reading bubbler heater data file')
                end
                Change_Status(app,'Off')
            end
        end

        % Button pushed function: ConnecttoMFCCButton
        function ConnecttoMFCCButtonPushed(app, event)
            ChangeInterlock(app,'clear MFC')
            a = true;
            b = true;
            c = true;
            try
                NbSetFlowdata = readmatrix(['Data Files/Mass Flow Files/',app.NbCl5MFCProfileDropDown.Value]);
                app.NbSetFlowTime = NbSetFlowdata(:,1);
                app.NbSetFlow = NbSetFlowdata(:,2);
            catch
                Update_Log(app,'Unable to read NbCl5 mass flow file')
                a = false;
            end
            try
                SnSetFlowdata = readmatrix(['Data Files/Mass Flow Files/',app.SnCl2MFCProfileDropDown.Value]);
                app.SnSetFlowTime = SnSetFlowdata(:,1);
                app.SnSetFlow = SnSetFlowdata(:,2);
            catch
                Update_Log(app,'Unable to read SnCl2 mass flow file')
                b = false;
            end
            try
                ArSetFlowdata = readmatrix(['Data Files/Mass Flow Files/',app.ArMFCProfileDropDown.Value]);
                app.ArSetFlowTime = ArSetFlowdata(:,1);
                app.ArSetFlow = ArSetFlowdata(:,2);
            catch
                Update_Log(app,'Unable to read Ar mass flow file')
                c = false;
            end
            if a && b && c
                ChangeInterlock(app,'MFC')
            end
        end

        % Button pushed function: PreviewNbCl5Button
        function PreviewNbCl5ButtonPushed(app, event)
            try
                switch app.MassFlowProfileDropDown.Value
                    case 'NbCl5'
                        PrevMFC(['Data Files/Mass Flow Files/',app.NbCl5MFCProfileDropDown.Value])
                    case 'SnCl2'
                        PrevMFC(['Data Files/Mass Flow Files/',app.SnCl2MFCProfileDropDown.Value])
                    case 'Ar'
                        PrevMFC(['Data Files/Mass Flow Files/',app.ArMFCProfileDropDown.Value])
                end
                
            catch
                Update_Log(app,['Unable to preview MFC file for ',app.MassFlowProfileDropDown.Value])
            end
        end

        % Button pushed function: CreateMFCProfileButton
        function CreateMFCProfileButtonPushed(app, event)
            CreateMFC(app);
        end

        % Value changed function: MassFlowProfileDropDown
        function MassFlowProfileDropDownValueChanged(app, event)
            app.PreviewNbCl5Button.Text = ['Preview ',app.MassFlowProfileDropDown.Value];
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            %this functin is the main loop of the program. while it runs,
            %it will continue update the data and display changing
            %conditions. If it is desired to add any safety showdown
            %checks, this is wehere to add them.
            if strcmp('Ready',app.ProgramStatusEditField.Value)
                app.TZero = now;
                Change_Status(app,'Active')
                try%this 'try' is due to an error thrown from the while condition upon closing the app 
                    if strcmp(app.SyncModeSwitch.Value, 'Automatic')
                       while app.ProgramStatusEditField.Value  == "Active" && not(app.IsSynced)
                            Update_Data(app)
                            if (app.Z1Temps(end) + app.Z2Temps(end) + app.Z3Temps(end))/3 >= app.DegassingTempCEditField.Value - app.TempTol/2
                                if (app.Z1Temps(end) + app.Z2Temps(end) + app.Z3Temps(end))/3 >= app.DegassingTempCEditField.Value + app.TempTol/2
                                    Update_Log(app,'Sync aborted. Furnace too hot')
                                else
                                    Update_Log(app,'Beginning Clock Synchronization')
                                    SyncTime(app)
                                end
                            end
                            pause(app.PauseLength)
                        end
                    elseif strcmp(app.SyncModeSwitch.Value,'Manual')
                        app.TZero = now;
                        Update_Data(app)
                        app.FurnaceDelay = app.FurnTime(end) - app.FurnTime(1);
                        app.SyncStatusLamp.Color = 'g';
                        app.NotSyncedLabel.Text = 'Synced';
                        app.IsSynced = true;
                        app.UScaleChanged = true;
                        app.CScaleChanged = true;
                        Update_Log(app,['Synced zeropoint at ',datestr(app.TZero)])
                        Update_Log(app,'Successfully Synced')
                    else
                        Change_Status(app,'Off')
                        Update_Log(app,'Sync Mode Error')
                    end
                    while app.ProgramStatusEditField.Value  == "Active"
                        %update data is where all the graphing and updating
                        %occurs. It is the heart and soul of this GUI
                        Update_Data(app)
                        pause(app.PauseLength)
                    end
                catch ME
                    switch ME.identifier
                        case 'MATLAB:class:InvalidHandle'
                            %warning('App has already been deleted');
                        otherwise
                            ME.message
                    end
                end
            elseif strcmp('Off',app.ProgramStatusEditField.Value)
                errordlg('Cannot start program unless interlock is complete','Uncomplete Interlock');
            else
                errordlg('Cannot start program while furnace is busy','Furnace Busy');
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            FailSafe(app)
        end

        % Button pushed function: SyncModeInfoButton
        function SyncModeInfoButtonPushed(app, event)
            uialert(app.UIFigure,"Manual syncing will begin MATLAB's clock as soon as the start button is pressed. " + ...
                "When using manual mode, be sure that once the furnace program is started, you quickly press the MATLAB start " + ...
                "button. Automatic mode waits for the furnace's temp to stablize at the degassing temp. Then it waits for the temp to " + ...
                "ramp up. It calculates the time at which the ramp began, and starts MATLAB's clock then. ",'Sync Mode Info','Icon','info')
        end

        % Value changed function: SyncModeSwitch
        function SyncModeSwitchValueChanged(app, event)
            if strcmp(app.SyncModeSwitch.Value,'Manual')
                app.DegassingTempCEditField.Enable = 'off';
            else
                app.DegassingTempCEditField.Enable = 'on';
            end
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            app.UScaleChanged = true;
            app.CScaleChanged = true;
            if strcmp(app.Switch.Value,'Custom Time')
                app.CustomControl = CustomTime(app);
            else
                try
                    delete(app.CustomControl)
                catch 
                end
            end            
        end

        % Value changed function: DisplaySettingsSwitch
        function DisplaySettingsSwitchValueChanged(app, event)
            app.UScaleChanged = true;
            app.CScaleChanged = true;
            if strcmp(app.Switch.Value, 'Custom Time')
                app.CustomControl.UIFigure.Visible = 'off';
                app.CustomControl.UIFigure.Visible = 'on';
            end
        end

        % Value changed function: DisplayMeasuredPressureCheckBox
        function DisplayMeasuredPressureCheckBoxValueChanged(app, event)
            app.UScaleChanged = true;
            app.CScaleChanged = true;
            cla(app.UIAxes_2)
            legend(app.UIAxes_2,'hide')
            if strcmp(app.Switch.Value, 'Custom Time')
                app.CustomControl.UIFigure.Visible = 'off';
                app.CustomControl.UIFigure.Visible = 'on';
            end
        end

        % Value changed function: DisplaySetPressureCheckBox
        function DisplaySetPressureCheckBoxValueChanged(app, event)
            app.UScaleChanged = true;
            app.CScaleChanged = true;
            cla(app.UIAxes_2)
            legend(app.UIAxes_2,'hide')
            if strcmp(app.Switch.Value, 'Custom Time')
                app.CustomControl.UIFigure.Visible = 'off';
                app.CustomControl.UIFigure.Visible = 'on';
            end
        end

        % Button pushed function: RefreshFilesButton
        function RefreshFilesButtonPushed(app, event)
            try
                f = dir('Data Files/Furnace Data Files/');
                files = {};
                if length(f)<3
                    error('No furnace data files were found')
                end
                for i = 3:length(f)
                    file = f(i);
                    files(i-2) = {file.name};
                end
                app.FurnaceDataFileDropDown.Items = files;
            catch
                Update_Log(app,'No furnace data files were found')
            end
            
            try
                f = dir('Data Files/Pressure Files/Set Pressure/');
                g = dir('Data Files/Pressure Files/Measured Pressure/');
                files = {};
                if length(f)<3 && length(g)<3
                    error('No pressure files were found')
                end
                for i = 3:length(f)
                    file = f(i);
                    files(i-2) = {file.name};
                end
                for i = 3:length(g)
                    file = g(i);
                    files(i-4+length(f)) = {file.name};
                end
                app.PressureProfileDropDown.Items = files;
            catch
                Update_Log(app,'No pressure files were found')
            end
            try
                f = dir('Data Files/Bubbler Files/');
                files = {};
                if length(f)<3
                    error('No bubbler files were found')
                end
                for i = 3:length(f)
                    file = f(i);
                    if strcmp(file.name(length(file.name)-2:length(file.name)),'csv')
                        files(length(files) + 1) = {file.name};
                    end
                end
                app.BubblerFileDropDown_2.Items = files;
                app.BubblerFileDropDown.Items = files;
            catch
                Update_Log(app,'No bubbler data files were found')
            end
            try
                f = dir('Data Files/Mass Flow Files/');
                files = {};
                if length(f)<3
                    error('No mass flow files were found')
                end
                for i = 3:length(f)
                    file = f(i);
                    files(i-2) = {file.name};
                end
                app.NbCl5MFCProfileDropDown.Items = files;
                app.SnCl2MFCProfileDropDown.Items = files;
                app.ArMFCProfileDropDown.Items = files;
            catch
                Update_Log(app,'No mass flow files were found')
            end
        end

        % Button pushed function: UIFormatting
        function UIFormattingCheck(app, event)
            formatcheck(app)
        end

        % Button pushed function: UIBackground
        function UIBackgroundCheck(app, event)
            formatcheck(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Furnace Temperature History')
            xlabel(app.UIAxes, 'Time (min)')
            ylabel(app.UIAxes, 'Measured Temp (C)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Pressure History')
            xlabel(app.UIAxes_2, 'Time (min)')
            ylabel(app.UIAxes_2, 'Pressure (Torr)')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Bubbler Heater Temperature History')
            xlabel(app.UIAxes_3, 'Time (min)')
            ylabel(app.UIAxes_3, 'Measured Temp (C)')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Mass Flow History')
            xlabel(app.UIAxes_4, 'Time (min)')
            ylabel(app.UIAxes_4, 'Mass Flow (sccm)')
            app.MeasuredValuesPanel = uipanel(app.UIFigure);
            app.MeasuredValuesPanel.Title = 'Measured Values';
            app.UITable = uitable(app.MeasuredValuesPanel);
            app.UITable.ColumnName = {'Actual Temp (C)'};
            app.UITable.RowName = {'Zone 1'; 'Zone 2'; 'Zone 3'};
            app.PressureTorrEditFieldLabel = uilabel(app.MeasuredValuesPanel);
            app.PressureTorrEditFieldLabel.Text = 'Pressure (Torr)';
            app.PressureTorrEditField = uieditfield(app.MeasuredValuesPanel, 'numeric');
            app.PressureTorrEditField.ValueDisplayFormat = '%.3f';
            app.PressureTorrEditField.Editable = 'off';
            app.PressureTorrEditField.Value = 760;
            app.UITable2 = uitable(app.MeasuredValuesPanel);
            app.UITable2.ColumnName = {'Flow Rate (sccm)'};
            app.UITable2.RowName = {};
            app.ValveOpennessEditFieldLabel = uilabel(app.MeasuredValuesPanel);
            app.ValveOpennessEditFieldLabel.Text = 'Valve Openness (%)';
            app.ValveOpennessEditField = uieditfield(app.MeasuredValuesPanel, 'numeric');
            app.ValveOpennessEditField.Limits = [0 100];
            app.ValveOpennessEditField.ValueDisplayFormat = '%.1f';
            app.ValveOpennessEditField.Editable = 'off';
            app.ValveOpennessEditField.Value = 100;
            app.UITable3 = uitable(app.MeasuredValuesPanel);
            app.UITable3.ColumnName = {'Bubbler Temp (C)'};
            app.UITable3.RowName = {'NbCl5'; 'SnCl2'};
            app.SystemLogTextAreaLabel = uilabel(app.UIFigure);
            app.SystemLogTextAreaLabel.Text = 'System Log';
            app.SystemLogTextArea = uitextarea(app.UIFigure);
            app.SystemLogTextArea.Editable = 'off';
            app.TripPointsPanel = uipanel(app.UIFigure);
            app.TripPointsPanel.Title = 'Trip Points';
            app.FurnaceTempCEditFieldLabel = uilabel(app.TripPointsPanel);
            app.FurnaceTempCEditFieldLabel.Text = 'Furnace Temp (C)';
            app.FurnaceTempCEditField = uieditfield(app.TripPointsPanel, 'numeric');
            app.FurnaceTempCEditField.Limits = [-273.15 Inf];
            app.FurnaceTempCEditField.Value = 1100;
            app.PressureTorrEditField_2Label = uilabel(app.TripPointsPanel);
            app.PressureTorrEditField_2Label.Text = 'Pressure (Torr)';
            app.PressureTorrEditField_2 = uieditfield(app.TripPointsPanel, 'numeric');
            app.PressureTorrEditField_2.Limits = [0 Inf];
            app.PressureTorrEditField_2.Value = 900;
            app.FurnaceSyncingControlsPanel = uipanel(app.UIFigure);
            app.FurnaceSyncingControlsPanel.Title = 'Furnace Syncing Controls';
            app.SyncStatusLampLabel = uilabel(app.FurnaceSyncingControlsPanel);
            app.SyncStatusLampLabel.Text = 'Sync Status';
            app.SyncStatusLamp = uilamp(app.FurnaceSyncingControlsPanel);
            app.NotSyncedLabel = uilabel(app.FurnaceSyncingControlsPanel);
            app.NotSyncedLabel.Text = 'Not Synced';
            app.DegassingTempCEditFieldLabel = uilabel(app.FurnaceSyncingControlsPanel);
            app.DegassingTempCEditFieldLabel.Text = 'Degassing Temp (C)';
            app.DegassingTempCEditField = uieditfield(app.FurnaceSyncingControlsPanel, 'numeric');
            app.DegassingTempCEditField.Enable = 'off';
            app.DegassingTempCEditField.Value = 50;
            app.SyncModeSwitchLabel = uilabel(app.FurnaceSyncingControlsPanel);
            app.SyncModeSwitchLabel.Text = 'Sync Mode';
            app.SyncModeSwitch = uiswitch(app.FurnaceSyncingControlsPanel, 'toggle');
            app.SyncModeSwitch.Items = {'Automatic', 'Manual'};
            app.SyncModeSwitch.ValueChangedFcn = createCallbackFcn(app, @SyncModeSwitchValueChanged, true);
            app.SyncModeSwitch.Value = 'Manual';
            app.SyncModeInfoButton = uibutton(app.FurnaceSyncingControlsPanel, 'push');
            app.SyncModeInfoButton.ButtonPushedFcn = createCallbackFcn(app, @SyncModeInfoButtonPushed, true);
            app.SyncModeInfoButton.Text = 'Sync Mode Info';
            app.MasterControlsPanel = uipanel(app.UIFigure);
            app.MasterControlsPanel.Title = 'Master Controls';
            app.StartButton = uibutton(app.MasterControlsPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Enable = 'off';
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.MasterControlsPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop';
            app.ProgramStatusLabel = uilabel(app.MasterControlsPanel);
            app.ProgramStatusLabel.Text = {'Program'; 'Status'};
            app.ProgramStatusEditField = uieditfield(app.MasterControlsPanel, 'text');
            app.ProgramStatusEditField.Editable = 'off';
            app.ProgramStatusEditField.Value = 'Off';
            app.Lamp = uilamp(app.MasterControlsPanel);
            app.FileSelectionPanel = uipanel(app.UIFigure);
            app.FileSelectionPanel.Title = 'File Selection';
            app.FurnaceDataPanel = uipanel(app.FileSelectionPanel);
            app.FurnaceDataPanel.Title = 'Furnace Data';
            app.FurnaceDataFileDropDownLabel = uilabel(app.FurnaceDataPanel);
            app.FurnaceDataFileDropDownLabel.Text = 'Furnace Data File';
            app.FurnaceDataFileDropDown = uidropdown(app.FurnaceDataPanel);
            app.FurnaceDataFileDropDown.Items = {};
            app.FurnaceDataFileDropDown.Value = {};
            app.CheckFuranceFileButton = uibutton(app.FurnaceDataPanel, 'push');
            app.CheckFuranceFileButton.ButtonPushedFcn = createCallbackFcn(app, @CheckFuranceFileButtonPushed, true);
            app.CheckFuranceFileButton.Text = 'Check Furance File';
            app.PressureDataPanel = uipanel(app.FileSelectionPanel);
            app.PressureDataPanel.Title = 'Pressure Data';
            app.PressureProfileDropDownLabel = uilabel(app.PressureDataPanel);
            app.PressureProfileDropDownLabel.Text = 'Pressure Profile';
            app.PressureProfileDropDown = uidropdown(app.PressureDataPanel);
            app.PressureProfileDropDown.Items = {};
            app.PressureProfileDropDown.Value = {};
            app.PreviewPressureProfileButton = uibutton(app.PressureDataPanel, 'push');
            app.PreviewPressureProfileButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewPressureProfileButtonPushed, true);
            app.PreviewPressureProfileButton.Text = 'Preview Pressure Profile';
            app.CreatePressureProfileButton = uibutton(app.PressureDataPanel, 'push');
            app.CreatePressureProfileButton.ButtonPushedFcn = createCallbackFcn(app, @CreatePressureProfileButtonPushed, true);
            app.CreatePressureProfileButton.Text = 'Create Pressure Profile';
            app.ConnecttoButterflyValveButton = uibutton(app.PressureDataPanel, 'push');
            app.ConnecttoButterflyValveButton.ButtonPushedFcn = createCallbackFcn(app, @ConnecttoButterflyValveButtonPushed, true);
            app.ConnecttoButterflyValveButton.Text = 'Connect to Butterfly Valve';
            app.BubblerHeaterDataPanel = uipanel(app.FileSelectionPanel);
            app.BubblerHeaterDataPanel.Title = 'Bubbler Heater Data';
            app.FurnaceDataFileDropDownLabel_2 = uilabel(app.BubblerHeaterDataPanel);
            app.FurnaceDataFileDropDownLabel_2.Text = 'NbCl5 Heater Data File';
            app.BubblerFileDropDown = uidropdown(app.BubblerHeaterDataPanel);
            app.BubblerFileDropDown.Items = {};
            app.BubblerFileDropDown.Value = {};
            app.FurnaceDataFileDropDownLabel_3 = uilabel(app.BubblerHeaterDataPanel);
            app.FurnaceDataFileDropDownLabel_3.Text = 'SnCl2 Heater Data File';
            app.BubblerFileDropDown_2 = uidropdown(app.BubblerHeaterDataPanel);
            app.BubblerFileDropDown_2.Items = {};
            app.BubblerFileDropDown_2.Value = {};
            app.CheckHeaterFilesButton = uibutton(app.BubblerHeaterDataPanel, 'push');
            app.CheckHeaterFilesButton.ButtonPushedFcn = createCallbackFcn(app, @CheckHeaterFilesButtonPushed, true);
            app.CheckHeaterFilesButton.Text = 'Check Heater Files';
            app.MassFlowControlDataPanel = uipanel(app.FileSelectionPanel);
            app.MassFlowControlDataPanel.Title = 'Mass Flow Control Data';
            app.NbCl5MFCProfileDropDownLabel = uilabel(app.MassFlowControlDataPanel);
            app.NbCl5MFCProfileDropDownLabel.Text = 'NbCl5 MFC Profile';
            app.NbCl5MFCProfileDropDown = uidropdown(app.MassFlowControlDataPanel);
            app.NbCl5MFCProfileDropDown.Items = {};
            app.NbCl5MFCProfileDropDown.Value = {};
            app.SnCl2MFCProfileDropDownLabel = uilabel(app.MassFlowControlDataPanel);
            app.SnCl2MFCProfileDropDownLabel.Text = 'SnCl2 MFC Profile';
            app.SnCl2MFCProfileDropDown = uidropdown(app.MassFlowControlDataPanel);
            app.SnCl2MFCProfileDropDown.Items = {};
            app.SnCl2MFCProfileDropDown.Value = {};
            app.ArMFCProfileDropDownLabel = uilabel(app.MassFlowControlDataPanel);
            app.ArMFCProfileDropDownLabel.Text = 'Ar MFC Profile';
            app.ArMFCProfileDropDown = uidropdown(app.MassFlowControlDataPanel);
            app.ArMFCProfileDropDown.Items = {};
            app.ArMFCProfileDropDown.Value = {};
            app.PreviewNbCl5Button = uibutton(app.MassFlowControlDataPanel, 'push');
            app.PreviewNbCl5Button.ButtonPushedFcn = createCallbackFcn(app, @PreviewNbCl5ButtonPushed, true);
            app.PreviewNbCl5Button.Text = 'Preview NbCl5';
            app.MassFlowProfileDropDown = uidropdown(app.MassFlowControlDataPanel);
            app.MassFlowProfileDropDown.Items = {'NbCl5', 'SnCl2', 'Ar'};
            app.MassFlowProfileDropDown.ValueChangedFcn = createCallbackFcn(app, @MassFlowProfileDropDownValueChanged, true);
            app.MassFlowProfileDropDown.Value = 'NbCl5';
            app.CreateMFCProfileButton = uibutton(app.MassFlowControlDataPanel, 'push');
            app.CreateMFCProfileButton.ButtonPushedFcn = createCallbackFcn(app, @CreateMFCProfileButtonPushed, true);
            app.CreateMFCProfileButton.Text = 'Create MFC Profile';
            app.ConnecttoMFCCButton = uibutton(app.MassFlowControlDataPanel, 'push');
            app.ConnecttoMFCCButton.ButtonPushedFcn = createCallbackFcn(app, @ConnecttoMFCCButtonPushed, true);
            app.ConnecttoMFCCButton.Text = 'Connect to MFCC';
            app.RefreshFilesButton = uibutton(app.FileSelectionPanel, 'push');
            app.RefreshFilesButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshFilesButtonPushed, true);
            app.RefreshFilesButton.Text = 'Refresh Files';
            app.PlottingSettingsPanel = uipanel(app.UIFigure);
            app.PlottingSettingsPanel.Title = 'Plotting Settings';
            app.TimeScaleControlPanel = uipanel(app.PlottingSettingsPanel);
            app.TimeScaleControlPanel.Title = 'Time Scale Control';
            app.Switch = uiswitch(app.TimeScaleControlPanel, 'toggle');
            app.Switch.Items = {'All Time', 'Custom Time'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Enable = 'off';
            app.Switch.Value = 'All Time';
            app.DisplaySettingsSwitchLabel = uilabel(app.TimeScaleControlPanel);
            app.DisplaySettingsSwitchLabel.Text = 'Display Settings';
            app.DisplaySettingsSwitch = uiswitch(app.TimeScaleControlPanel, 'toggle');
            app.DisplaySettingsSwitch.Items = {'Elasped Time', 'Actual Time'};
            app.DisplaySettingsSwitch.ValueChangedFcn = createCallbackFcn(app, @DisplaySettingsSwitchValueChanged, true);
            app.DisplaySettingsSwitch.Enable = 'off';
            app.DisplaySettingsSwitch.Value = 'Elasped Time';
            app.DisplaySettingsPanel = uipanel(app.PlottingSettingsPanel);
            app.DisplaySettingsPanel.Title = 'Display Settings';
            app.DisplaySetPressureCheckBox = uicheckbox(app.DisplaySettingsPanel);
            app.DisplaySetPressureCheckBox.ValueChangedFcn = createCallbackFcn(app, @DisplaySetPressureCheckBoxValueChanged, true);
            app.DisplaySetPressureCheckBox.Enable = 'off';
            app.DisplaySetPressureCheckBox.Text = 'Display Set Pressure';
            app.DisplaySetPressureCheckBox.Value = true;
            app.DisplayMeasuredPressureCheckBox = uicheckbox(app.DisplaySettingsPanel);
            app.DisplayMeasuredPressureCheckBox.ValueChangedFcn = createCallbackFcn(app, @DisplayMeasuredPressureCheckBoxValueChanged, true);
            app.DisplayMeasuredPressureCheckBox.Enable = 'off';
            app.DisplayMeasuredPressureCheckBox.Text = 'Display Measured Pressure';
            app.DisplayMeasuredPressureCheckBox.Value = true;
            app.DigitalInterlockPanel = uipanel(app.UIFigure);
            app.DigitalInterlockPanel.Title = 'Digital Interlock';
            app.FurnaceLampLabel = uilabel(app.DigitalInterlockPanel);
            app.FurnaceLampLabel.Text = 'Furnace';
            app.FurnaceLamp = uilamp(app.DigitalInterlockPanel);
            app.ButterflyValveLabel = uilabel(app.DigitalInterlockPanel);
            app.ButterflyValveLabel.Text = {'Butterfly'; 'Valve'; ''};
            app.ButterflyValveLamp = uilamp(app.DigitalInterlockPanel);
            app.BubblerHeatersLabel = uilabel(app.DigitalInterlockPanel);
            app.BubblerHeatersLabel.Text = {'Bubbler'; 'Heaters'; ''};
            app.BubblerHeatersLamp = uilamp(app.DigitalInterlockPanel);
            app.MassFlowLampLabel = uilabel(app.DigitalInterlockPanel);
            app.MassFlowLampLabel.Text = {'Mass'; 'Flow'};
            app.MassFlowLamp = uilamp(app.DigitalInterlockPanel);
            app.UIBackground = uibutton(app.UIFigure, 'push');
            app.UIBackground.ButtonPushedFcn = createCallbackFcn(app, @UIBackgroundCheck, true);
            app.UIBackground.Visible = 'off';
            app.UIBackground.Text = '';
            app.UIFormatting = uibutton(app.UIFigure, 'push');
            app.UIFormatting.ButtonPushedFcn = createCallbackFcn(app, @UIFormattingCheck, true);
            app.UIFormatting.Visible = 'off';
            app.UIFormatting.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CVDSystemGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end