classdef CreateMFC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        FileFinalizerPanel        matlab.ui.container.Panel
        FileNameEditField         matlab.ui.control.EditField
        FileNameEditFieldLabel    matlab.ui.control.Label
        ExportFileButton          matlab.ui.control.Button
        txtLabel                  matlab.ui.control.Label
        ProfileCreaterPanel       matlab.ui.container.Panel
        sccmminLabel              matlab.ui.control.Label
        RampRateEditField         matlab.ui.control.NumericEditField
        RampRateEditFieldLabel    matlab.ui.control.Label
        AddRampStepButton         matlab.ui.control.Button
        ClearAllButton            matlab.ui.control.Button
        DeletePreviousStepButton  matlab.ui.control.Button
        AddConstantStepButton     matlab.ui.control.Button
        sccmLabel                 matlab.ui.control.Label
        DropDown                  matlab.ui.control.DropDown
        TimeEditField             matlab.ui.control.NumericEditField
        TimeEditFieldLabel        matlab.ui.control.Label
        MassFlowEditField         matlab.ui.control.NumericEditField
        MassFlowEditFieldLabel    matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        flow = [0];
        time = [0];
        CallingApp
    end
    
    methods (Access = private)
        
        function plotflow(app)
            if isempty(app.time)
                cla(app.UIAxes)
            else
                if app.time(end)>240
                    plot(app.UIAxes,app.time/60,app.flow)
                    app.UIAxes.XLabel.String = 'Time (hr)';
                elseif app.time(end)<1
                    plot(app.UIAxes,app.time*60,app.flow)
                    app.UIAxes.XLabel.String = 'Time (sec)';
                else
                    plot(app.UIAxes,app.time,app.flow)
                    app.UIAxes.XLabel.String = 'Time (min)';
                end
            end
            Update(app)
        end
        
        function Update(app)
            if app.TimeEditField.Value > 0
                if app.MassFlowEditField.Value == app.flow(end)
                    app.AddRampStepButton.Enable = 'off';
                else
                    app.AddRampStepButton.Enable = 'on';
                end
                app.AddConstantStepButton.Enable = 'on';
                app.RampRateEditField.Value = (app.MassFlowEditField.Value-app.flow(end))/app.TimeEditField.Value;
            else
                app.AddRampStepButton.Enable = 'off';
                app.AddRampStepButton.Enable = 'off';
                app.RampRateEditField.Value = 0;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp = mainapp;
        end

        % Button pushed function: ExportFileButton
        function ExportFileButtonPushed(app, event)
            f = dir('Data Files/Mass Flow Files/');
            write = true;
            if length(f)>2
                files = {};
                for i = 3:length(f)
                    file = f(i);
                    files(i-2) = {file.name};
                end
                if ismember([app.FileNameEditField.Value,'.txt'], files)
                    YN = uiconfirm(app.UIFigure,'This file already exists. Overwrite?', 'File already exists');
                    if strcmpi(YN,'OK')
                        write = true;
                    else
                        write = false;
                    end
                elseif strcmp(app.FileNameEditField.Value,'')
                    errordlg('File name cannot be blank.','Invalid File Name')
                    write = false;
                end
            end
            if write
                try
                    datafile = fopen(['Data Files/Mass Flow Files/',app.FileNameEditField.Value,'.txt'],'w');
                    fprintf(datafile, '%f,%f\n',[app.time; app.flow]);
                    fclose(datafile);
                    f = dir('Data Files/Mass Flow Files/');
                    files = {};
                    for i = 3:length(f)
                        file = f(i);
                        files(i-2) = {file.name};
                    end
                    app.CallingApp.NbCl5MFCProfileDropDown.Items = files;
                    app.CallingApp.SnCl2MFCProfileDropDown.Items = files;
                    app.CallingApp.ArMFCProfileDropDown.Items = files;
                    delete(app)
                    %this file format mimics the ones created by the MTI
                    %bubbler heater software the time is units of min and
                    %mass flow in sccm
                catch
                    errordlg('File could not be saved with current name.','Invalid File Name')
                end
            end
        end

        % Button pushed function: ClearAllButton
        function ClearAllButtonPushed(app, event)
            app.flow = [0];
            app.time = [0];
            app.ExportFileButton.Enable = 'off';
            Update(app)
            app.DeletePreviousStepButton.Enable = 'off';
            cla(app.UIAxes)
        end

        % Value changed function: MassFlowEditField
        function MassFlowEditFieldValueChanged(app, event)
            Update(app)
        end

        % Value changed function: TimeEditField
        function TimeEditFieldValueChanged(app, event)
            Update(app)
        end

        % Button pushed function: DeletePreviousStepButton
        function DeletePreviousStepButtonPushed(app, event)
            if length(app.time) == 2
                ClearAllButtonPushed(app)
            else
                app.time = app.time(1:length(app.time)-2);
                app.flow = app.flow(1:length(app.flow)-2);
                plotflow(app)
            end
        end

        % Button pushed function: AddConstantStepButton
        function AddConstantStepButtonPushed(app, event)
            if app.MassFlowEditField.Value>=0 && app.TimeEditField.Value>0
                if length(app.time) == 1
                    switch app.DropDown.Value
                        case 'min'
                            app.time = [app.time,app.TimeEditField.Value];%time will always be in minutes
                        case 'sec'
                            app.time = [app.time,app.TimeEditField.Value/60];
                        case 'hr'
                            app.time = [app.time,app.TimeEditField.Value*60];
                    end
                    app.flow = [];%this ensures that there are only two data points added in the begninning
                else
                    switch app.DropDown.Value
                        case 'min'
                            app.time = [app.time,app.time(end),app.time(end)+app.TimeEditField.Value];%time will always be in minutes
                        case 'sec'
                            app.time = [app.time,app.time(end),app.time(end)+app.TimeEditField.Value/60];
                        case 'hr'
                            app.time = [app.time,app.time(end),app.time(end)+app.TimeEditField.Value*60];
                    end
                end
                app.flow = [app.flow,app.MassFlowEditField.Value,app.MassFlowEditField.Value];%in units of sccm
                plotflow(app)
                app.DeletePreviousStepButton.Enable = 'on';
                app.ExportFileButton.Enable = 'on';
            else
                uialert(app.UIFigure,'Ensure time > 0 and mass flow >= 0.','Value Error')
            end
        end

        % Button pushed function: AddRampStepButton
        function AddRampStepButtonPushed(app, event)
            if (app.MassFlowEditField.Value>=0 && app.TimeEditField.Value>0) || app.MassFlowEditField.Value == app.flow(end)
                if length(app.time) == 1
                    switch app.DropDown.Value
                        case 'min'
                            app.time = [app.time,app.TimeEditField.Value];%time will always be in minutes
                        case 'sec'
                            app.time = [app.time,app.TimeEditField.Value/60];
                        case 'hr'
                            app.time = [app.time,app.TimeEditField.Value*60];
                    end
                    app.flow = [app.flow,app.MassFlowEditField.Value];%in units of sccm
                else
                    switch app.DropDown.Value
                        case 'min'
                            app.time = [app.time,app.time(end),app.time(end)+app.TimeEditField.Value];%time will always be in minutes
                        case 'sec'
                            app.time = [app.time,app.time(end),app.time(end)+app.TimeEditField.Value/60];
                        case 'hr'
                            app.time = [app.time,app.time(end),app.time(end)+app.TimeEditField.Value*60];
                    end
                    app.flow = [app.flow,app.flow(end),app.MassFlowEditField.Value];%in units of sccm
                end
                plotflow(app)
                app.DeletePreviousStepButton.Enable = 'on';
                app.ExportFileButton.Enable = 'on';
            else
                uialert(app.UIFigure,'Ensure time > 0, mass flow >= 0 and the ramp rate is nonzero.','Value Error')
            end
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            app.sccmminLabel.Text = ['sccm/',app.DropDown.Value];
            Update(app)
        end

        % Value changed function: RampRateEditField
        function RampRateEditFieldValueChanged(app, event)
            if app.RampRateEditField.Value ~= (app.MassFlowEditField.Value-app.flow(end))/app.TimeEditField.Value
                app.TimeEditField.Value= (app.MassFlowEditField.Value-app.flow(end))/app.RampRateEditField.Value;
            end
            Update(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Mass Flow Profile')
            xlabel(app.UIAxes, 'Time (min)')
            ylabel(app.UIAxes, 'Mass Flow (sccm)')
            app.ProfileCreaterPanel = uipanel(app.UIFigure);
            app.ProfileCreaterPanel.Title = 'Profile Creater';
            app.MassFlowEditFieldLabel = uilabel(app.ProfileCreaterPanel);
            app.MassFlowEditFieldLabel.Text = 'Mass Flow';
            app.MassFlowEditField = uieditfield(app.ProfileCreaterPanel, 'numeric');
            app.MassFlowEditField.Limits = [0 Inf];
            app.MassFlowEditField.ValueChangedFcn = createCallbackFcn(app, @MassFlowEditFieldValueChanged, true);
            app.TimeEditFieldLabel = uilabel(app.ProfileCreaterPanel);
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeEditField = uieditfield(app.ProfileCreaterPanel, 'numeric');
            app.TimeEditField.Limits = [0 Inf];
            app.TimeEditField.ValueChangedFcn = createCallbackFcn(app, @TimeEditFieldValueChanged, true);
            app.DropDown = uidropdown(app.ProfileCreaterPanel);
            app.DropDown.Items = {'sec', 'min', 'hr'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'min';
            app.sccmLabel = uilabel(app.ProfileCreaterPanel);
            app.sccmLabel.Text = 'sccm';
            app.AddConstantStepButton = uibutton(app.ProfileCreaterPanel, 'push');
            app.AddConstantStepButton.ButtonPushedFcn = createCallbackFcn(app, @AddConstantStepButtonPushed, true);
            app.AddConstantStepButton.Enable = 'off';
            app.AddConstantStepButton.Text = 'Add Constant Step';
            app.DeletePreviousStepButton = uibutton(app.ProfileCreaterPanel, 'push');
            app.DeletePreviousStepButton.ButtonPushedFcn = createCallbackFcn(app, @DeletePreviousStepButtonPushed, true);
            app.DeletePreviousStepButton.Enable = 'off';
            app.DeletePreviousStepButton.Text = 'Delete Previous Step';
            app.ClearAllButton = uibutton(app.ProfileCreaterPanel, 'push');
            app.ClearAllButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButtonPushed, true);
            app.ClearAllButton.Text = 'Clear All';
            app.AddRampStepButton = uibutton(app.ProfileCreaterPanel, 'push');
            app.AddRampStepButton.ButtonPushedFcn = createCallbackFcn(app, @AddRampStepButtonPushed, true);
            app.AddRampStepButton.Enable = 'off';
            app.AddRampStepButton.Text = 'Add Ramp Step';
            app.RampRateEditFieldLabel = uilabel(app.ProfileCreaterPanel);
            app.RampRateEditFieldLabel.Text = 'Ramp Rate';
            app.RampRateEditField = uieditfield(app.ProfileCreaterPanel, 'numeric');
            app.RampRateEditField.ValueChangedFcn = createCallbackFcn(app, @RampRateEditFieldValueChanged, true);
            app.sccmminLabel = uilabel(app.ProfileCreaterPanel);
            app.sccmminLabel.Text = 'sccm/min';
            app.FileFinalizerPanel = uipanel(app.UIFigure);
            app.FileFinalizerPanel.Title = 'File Finalizer';
            app.txtLabel = uilabel(app.FileFinalizerPanel);
            app.txtLabel.Text = '.txt';
            app.ExportFileButton = uibutton(app.FileFinalizerPanel, 'push');
            app.ExportFileButton.ButtonPushedFcn = createCallbackFcn(app, @ExportFileButtonPushed, true);
            app.ExportFileButton.Enable = 'off';
            app.ExportFileButton.Text = 'Export File';
            app.FileNameEditFieldLabel = uilabel(app.FileFinalizerPanel);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.FileFinalizerPanel, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CreateMFC(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end