classdef CreatePres < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ProfileCreaterPanel       matlab.ui.container.Panel
        ClearAllButton            matlab.ui.control.Button
        DeletePreviousStepButton  matlab.ui.control.Button
        AddStepButton             matlab.ui.control.Button
        TorrLabel                 matlab.ui.control.Label
        DropDown                  matlab.ui.control.DropDown
        TimeEditField             matlab.ui.control.NumericEditField
        TimeEditFieldLabel        matlab.ui.control.Label
        PressureEditField         matlab.ui.control.NumericEditField
        PressureEditFieldLabel    matlab.ui.control.Label
        FileFinalizerPanel        matlab.ui.container.Panel
        FileNameEditField         matlab.ui.control.EditField
        FileNameEditFieldLabel    matlab.ui.control.Label
        ExportFileButton          matlab.ui.control.Button
        txtLabel                  matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        pres = [];
        time = [];
        CallingApp
    end
    
    methods (Access = private)
        
        function plotpres(app)
            if isempty(app.time)
                cla(app.UIAxes)
            else
                if app.time(length(app.time))>240
                    plot(app.UIAxes,app.time/60,app.pres)
                    app.UIAxes.XLabel.String = 'Time (hr)';
                elseif app.time(length(app.time))<1
                    plot(app.UIAxes,app.time*60,app.pres)
                    app.UIAxes.XLabel.String = 'Time (sec)';
                else
                    plot(app.UIAxes,app.time,app.pres)
                    app.UIAxes.XLabel.String = 'Time (min)';
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp = mainapp;
        end

        % Button pushed function: ExportFileButton
        function ExportFileButtonPushed(app, event)
            f = dir('Data Files/Pressure Files/');
            write = true;
            if length(f)>2
                files = {};
                for i = 3:length(f)
                    file = f(i);
                    files(i-2) = {file.name};
                end
                if ismember([app.FileNameEditField.Value,'.txt'], files)
                    YN = uiconfirm(app.UIFigure,'This file already exists. Overwrite?', 'File already exists');
                    if strcmpi(YN,'OK')
                        write = true;
                    else
                        write = false;
                    end
                elseif strcmp(app.FileNameEditField.Value,'')
                    errordlg('File name cannot be blank.','Invalid File Name')
                    write = false;
                end
            end
            if write
                %try
                    datafile = fopen(['Data Files/Pressure Files/Set Pressure/',app.FileNameEditField.Value,'.txt'],'w');
                    fprintf(datafile, '%f,%f\n',[app.time; app.pres]);
                    fclose(datafile);
                    f = dir('Data Files/Pressure Files/Set Pressure/');
                    g = dir('Data Files/Pressure Files/Measured Pressure/');
                    files = {};
                    for i = 3:length(f)
                        file = f(i);
                        files(i-2) = {file.name};
                    end
                    for i = 3:length(g)
                    file = g(i);
                    files(i-4+length(f)) = {file.name};
                    end
                    app.CallingApp.PressureProfileDropDown.Items = files;
                    delete(app)
                    %this file format mimics the ones created by the MTI
                    %bubbler heater software the time is units of min and
                    %pressure is in torr
                %catch
                    %errordlg('File could not be saved with current name.','Invalid File Name')
                %end
            end
        end

        % Button pushed function: ClearAllButton
        function ClearAllButtonPushed(app, event)
            app.pres = [];
            app.time = [];
            app.ExportFileButton.Enable = 'off';
            if app.PressureEditField.Value<0 || app.TimeEditField.Value<=0
                app.AddStepButton.Enable = 'off';
            end
            app.DeletePreviousStepButton.Enable = 'off';
            cla(app.UIAxes)
        end

        % Value changed function: PressureEditField
        function PressureEditFieldValueChanged(app, event)
            if app.PressureEditField.Value>=0 && app.TimeEditField.Value>0
                app.AddStepButton.Enable = 'on';
            end
        end

        % Value changed function: TimeEditField
        function TimeEditFieldValueChanged(app, event)
            if app.PressureEditField.Value>=0 && app.TimeEditField.Value>0
                app.AddStepButton.Enable = 'on';
            end
        end

        % Button pushed function: AddStepButton
        function AddStepButtonPushed(app, event)
            if app.PressureEditField.Value>=0 && app.TimeEditField.Value>0
                if isempty(app.time)
                    app.time = [0];
                    switch app.DropDown.Value
                        case 'min'
                            app.time = [app.time,app.TimeEditField.Value];%time will always be in minutes
                        case 'sec'
                            app.time = [app.time,app.TimeEditField.Value/60];
                        case 'hr'
                            app.time = [app.time,app.TimeEditField.Value*60];
                    end
                else
                    switch app.DropDown.Value
                        case 'min'
                            app.time = [app.time,app.time(length(app.time)),app.time(length(app.time))+app.TimeEditField.Value];%time will always be in minutes
                        case 'sec'
                            app.time = [app.time,app.time(length(app.time)),app.time(length(app.time))+app.TimeEditField.Value/60];
                        case 'hr'
                            app.time = [app.time,app.time(length(app.time)),app.time(length(app.time))+app.TimeEditField.Value*60];
                    end
                end
                app.pres = [app.pres,app.PressureEditField.Value,app.PressureEditField.Value];%in units of Torr
                plotpres(app)
                app.DeletePreviousStepButton.Enable = 'on';
                app.ExportFileButton.Enable = 'on';
            else
                uialert(app.UIFigure,'Ensure time > 0 and pressure >= 0.','Value Error')
            end
        end

        % Button pushed function: DeletePreviousStepButton
        function DeletePreviousStepButtonPushed(app, event)
            if length(app.time) == 2
                ClearAllButtonPushed(app)
            else
                app.time = app.time(1:length(app.time)-2);
                app.pres = app.pres(1:length(app.pres)-2);
                plotpres(app)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Pressure Profile')
            xlabel(app.UIAxes, 'Time (min)')
            ylabel(app.UIAxes, 'Pressure (Torr)')
            app.FileFinalizerPanel = uipanel(app.UIFigure);
            app.FileFinalizerPanel.Title = 'File Finalizer';
            app.txtLabel = uilabel(app.FileFinalizerPanel);
            app.txtLabel.Text = '.txt';
            app.ExportFileButton = uibutton(app.FileFinalizerPanel, 'push');
            app.ExportFileButton.ButtonPushedFcn = createCallbackFcn(app, @ExportFileButtonPushed, true);
            app.ExportFileButton.Enable = 'off';
            app.ExportFileButton.Text = 'Export File';
            app.FileNameEditFieldLabel = uilabel(app.FileFinalizerPanel);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.FileFinalizerPanel, 'text');
            app.ProfileCreaterPanel = uipanel(app.UIFigure);
            app.ProfileCreaterPanel.Title = 'Profile Creater';
            app.PressureEditFieldLabel = uilabel(app.ProfileCreaterPanel);
            app.PressureEditFieldLabel.Text = 'Pressure';
            app.PressureEditField = uieditfield(app.ProfileCreaterPanel, 'numeric');
            app.PressureEditField.Limits = [0 Inf];
            app.PressureEditField.ValueChangedFcn = createCallbackFcn(app, @PressureEditFieldValueChanged, true);
            app.TimeEditFieldLabel = uilabel(app.ProfileCreaterPanel);
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeEditField = uieditfield(app.ProfileCreaterPanel, 'numeric');
            app.TimeEditField.Limits = [0 Inf];
            app.TimeEditField.ValueChangedFcn = createCallbackFcn(app, @TimeEditFieldValueChanged, true);
            app.DropDown = uidropdown(app.ProfileCreaterPanel);
            app.DropDown.Items = {'sec', 'min', 'hr'};
            app.DropDown.Value = 'min';
            app.TorrLabel = uilabel(app.ProfileCreaterPanel);
            app.TorrLabel.Text = 'Torr';
            app.AddStepButton = uibutton(app.ProfileCreaterPanel, 'push');
            app.AddStepButton.ButtonPushedFcn = createCallbackFcn(app, @AddStepButtonPushed, true);
            app.AddStepButton.Enable = 'off';
            app.AddStepButton.Text = 'Add Step';
            app.DeletePreviousStepButton = uibutton(app.ProfileCreaterPanel, 'push');
            app.DeletePreviousStepButton.ButtonPushedFcn = createCallbackFcn(app, @DeletePreviousStepButtonPushed, true);
            app.DeletePreviousStepButton.Enable = 'off';
            app.DeletePreviousStepButton.Text = 'Delete Previous Step';
            app.ClearAllButton = uibutton(app.ProfileCreaterPanel, 'push');
            app.ClearAllButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButtonPushed, true);
            app.ClearAllButton.Text = 'Clear All';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CreatePres(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end