classdef CustomTime < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        FastForwardButton          matlab.ui.control.Button
        RewindButton               matlab.ui.control.Button
        DropDown_2                 matlab.ui.control.DropDown
        StartingTimeSpinner        matlab.ui.control.Spinner
        StartingTimeSpinnerLabel   matlab.ui.control.Label
        DropDown                   matlab.ui.control.DropDown
        DisplayLastTime            matlab.ui.control.CheckBox
        DisplayTimeEditField       matlab.ui.control.NumericEditField
        DisplayTimeEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp
    end
    
    methods (Access = private)
        
        function updatelabel(app)
            app.DisplayTimeEditField.Value = round(app.DisplayTimeEditField.Value);
            app.StartingTimeSpinner.Value = round(app.StartingTimeSpinner.Value);
            s = ' ';
            app.DisplayLastTime.Text = ['Display Last',s,int2str(app.DisplayTimeEditField.Value),s,app.DropDown.Value];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp = mainapp;
        end

        % Value changed function: DisplayLastTime
        function DisplayLastTimeValueChanged(app, event)
            if app.DisplayLastTime.Value
                app.StartingTimeSpinner.Enable = 'off';
                app.DropDown_2.Enable = 'off';
                app.RewindButton.Enable = 'off';
                app.FastForwardButton.Enable = 'off';
            else
                app.StartingTimeSpinner.Enable = 'on';
                app.DropDown_2.Enable = 'on';
                app.RewindButton.Enable = 'on';
                app.FastForwardButton.Enable = 'on';
            end
            app.CallingApp.UScaleChanged = true;
            app.CallingApp.CScaleChanged = true;
        end

        % Value changed function: DisplayTimeEditField
        function DisplayTimeEditFieldValueChanged(app, event)
            updatelabel(app)
            app.CallingApp.UScaleChanged = true;
            app.CallingApp.CScaleChanged = true;
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            updatelabel(app)
            app.CallingApp.UScaleChanged = true;
            app.CallingApp.CScaleChanged = true;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if strcmp(app.CallingApp.Switch.Value, 'All Time')
                delete(app)
            end
        end

        % Button pushed function: RewindButton
        function RewindButtonPushed(app, event)
            app.CallingApp.UScaleChanged = true;
            app.CallingApp.CScaleChanged = true;
            if strcmp(app.DropDown.Value,app.DropDown_2.Value)
                app.StartingTimeSpinner.Value = app.StartingTimeSpinner.Value - app.DisplayTimeEditField.Value;
            end
            switch app.DropDown.Value
                case 'hr'
                    switch app.DropDown_2.Value
                        case 'min'
                            app.StartingTimeSpinner.Value = app.StartingTimeSpinner.Value - app.DisplayTimeEditField.Value*60;
                        case 'sec'
                            app.StartingTimeSpinner.Value = app.StartingTimeSpinner.Value - app.DisplayTimeEditField.Value*3600;
                    end
                case 'min'
                    switch app.DropDown_2.Value
                        case 'hr'
                            if app.DisplayTimeEditField.Value/60 >= 1
                                app.StartingTimeSpinner.Value = app.StartingTimeSpinner.Value - app.DisplayTimeEditField.Value/60;
                            else
                                app.StartingTimeSpinner.Value = app.StartingTimeSpinner.Value - 1;
                            end
                        case 'sec'
                            app.StartingTimeSpinner.Value = app.StartingTimeSpinner.Value - app.DisplayTimeEditField.Value*60;
                    end
                case 'sec'
                    switch app.DropDown_2.Value
                        case 'min'
                            if app.DisplayTimeEditField.Value/60 >= 1
                                app.StartingTimeSpinner.Value = app.StartingTimeSpinner.Value - app.DisplayTimeEditField.Value/60;
                            else
                                app.StartingTimeSpinner.Value = app.StartingTimeSpinner.Value - 1;
                            end
                        case 'hr'
                            if app.DisplayTimeEditField.Value/3600 >= 1
                                app.StartingTimeSpinner.Value = app.StartingTimeSpinner.Value - app.DisplayTimeEditField.Value/3600;
                            else
                                app.StartingTimeSpinner.Value = app.StartingTimeSpinner.Value - 1;
                            end
                    end
            end
        end

        % Button pushed function: FastForwardButton
        function FastForwardButtonPushed(app, event)
            app.CallingApp.UScaleChanged = true;
            app.CallingApp.CScaleChanged = true;
            if strcmp(app.DropDown.Value,app.DropDown_2.Value)
                app.StartingTimeSpinner.Value = app.StartingTimeSpinner.Value + app.DisplayTimeEditField.Value;
            end
            switch app.DropDown.Value
                case 'hr'
                    switch app.DropDown_2.Value
                        case 'min'
                            app.StartingTimeSpinner.Value = app.StartingTimeSpinner.Value + app.DisplayTimeEditField.Value*60;
                        case 'sec'
                            app.StartingTimeSpinner.Value = app.StartingTimeSpinner.Value + app.DisplayTimeEditField.Value*3600;
                    end
                case 'min'
                    switch app.DropDown_2.Value
                        case 'hr'
                            if app.DisplayTimeEditField.Value/60 >= 1
                                app.StartingTimeSpinner.Value = app.StartingTimeSpinner.Value + app.DisplayTimeEditField.Value/60;
                            else
                                app.StartingTimeSpinner.Value = app.StartingTimeSpinner.Value + 1;
                            end
                        case 'sec'
                            app.StartingTimeSpinner.Value = app.StartingTimeSpinner.Value + app.DisplayTimeEditField.Value*60;
                    end
                case 'sec'
                    switch app.DropDown_2.Value
                        case 'min'
                            if app.DisplayTimeEditField.Value/60 >= 1
                                app.StartingTimeSpinner.Value = app.StartingTimeSpinner.Value + app.DisplayTimeEditField.Value/60;
                            else
                                app.StartingTimeSpinner.Value = app.StartingTimeSpinner.Value + 1;
                            end
                        case 'hr'
                            if app.DisplayTimeEditField.Value/3600 >= 1
                                app.StartingTimeSpinner.Value = app.StartingTimeSpinner.Value + app.DisplayTimeEditField.Value/3600;
                            else
                                app.StartingTimeSpinner.Value = app.StartingTimeSpinner.Value + 1;
                            end
                    end
            end
        end

        % Value changed function: StartingTimeSpinner
        function StartingTimeSpinnerValueChanged(app, event)
            app.CallingApp.UScaleChanged = true;
            app.CallingApp.CScaleChanged = true;
        end

        % Value changed function: DropDown_2
        function DropDown_2ValueChanged(app, event)
            app.CallingApp.UScaleChanged = true;
            app.CallingApp.CScaleChanged = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.DisplayTimeEditFieldLabel = uilabel(app.UIFigure);
            app.DisplayTimeEditFieldLabel.Text = 'Display Time';
            app.DisplayTimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.DisplayTimeEditField.ValueChangedFcn = createCallbackFcn(app, @DisplayTimeEditFieldValueChanged, true);
            app.DisplayTimeEditField.Value = 30;
            app.DisplayLastTime = uicheckbox(app.UIFigure);
            app.DisplayLastTime.ValueChangedFcn = createCallbackFcn(app, @DisplayLastTimeValueChanged, true);
            app.DisplayLastTime.Text = 'Display Last 30 sec';
            app.DisplayLastTime.Value = true;
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'sec', 'min', 'hr'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'sec';
            app.StartingTimeSpinnerLabel = uilabel(app.UIFigure);
            app.StartingTimeSpinnerLabel.Text = 'Starting Time';
            app.StartingTimeSpinner = uispinner(app.UIFigure);
            app.StartingTimeSpinner.ValueChangedFcn = createCallbackFcn(app, @StartingTimeSpinnerValueChanged, true);
            app.StartingTimeSpinner.Enable = 'off';
            app.DropDown_2 = uidropdown(app.UIFigure);
            app.DropDown_2.Items = {'sec', 'min', 'hr'};
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.DropDown_2.Enable = 'off';
            app.DropDown_2.Value = 'sec';
            app.RewindButton = uibutton(app.UIFigure, 'push');
            app.RewindButton.ButtonPushedFcn = createCallbackFcn(app, @RewindButtonPushed, true);
            app.RewindButton.Enable = 'off';
            app.RewindButton.Text = 'Rewind';
            app.FastForwardButton = uibutton(app.UIFigure, 'push');
            app.FastForwardButton.ButtonPushedFcn = createCallbackFcn(app, @FastForwardButtonPushed, true);
            app.FastForwardButton.Enable = 'off';
            app.FastForwardButton.Text = 'Fast Forward';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CustomTime(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end