classdef PrevMFC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        UIAxes    matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mfcfile)
            data = readmatrix(mfcfile);
            time = data(:,1);
            flow = data(:,2);
            app.UIAxes.Title.String = ['MFC Profile of ',mfcfile(28:end)];
            if isempty(time)
                cla(app.UIAxes)
            else
                if time(length(time))>240
                    plot(app.UIAxes,time/60,flow)
                    app.UIAxes.XLabel.String = 'Time (hr)';
                elseif time(length(time))<1
                    plot(app.UIAxes,time*60,flow)
                    app.UIAxes.XLabel.String = 'Time (sec)';
                else
                    plot(app.UIAxes,time,flow)
                    app.UIAxes.XLabel.String = 'Time (min)';
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'MFC Profile')
            xlabel(app.UIAxes, 'Time (min)')
            ylabel(app.UIAxes, 'Flow Rate (sccm)')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PrevMFC(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end