classdef PrevPres < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        UIAxes    matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, presfile)
            try
                if strcmp(presfile(length(presfile)-2:length(presfile)),'txt')
                    data = readmatrix(['Data Files/Pressure Files/Set Pressure/',presfile]);
                    time = data(:,1);
                    pres = data(:,2);
                elseif strcmp(presfile(length(presfile)-2:length(presfile)),'csv')
                    data = readmatrix(['Data Files/Pressure Files/Measured Pressure/',presfile]);
                    firstTime = data(1,1);
                    if isnan(firstTime)
                        error([presfile,' is empty'])
                    end
                    time = 1440*(data(:,1)-firstTime);
                    pres = data(:,2);
                else
                    error('Unable to read pressure file')
                end
            
                app.UIAxes.Title.String = ['Pressure Profile of ',presfile];
                if isempty(time)
                    cla(app.UIAxes)
                else
                    if time(length(time))>240
                        plot(app.UIAxes,time/60,pres)
                        app.UIAxes.XLabel.String = 'Time (hr)';
                    elseif time(length(time))<1
                        plot(app.UIAxes,time*60,pres)
                        app.UIAxes.XLabel.String = 'Time (sec)';
                    else
                        plot(app.UIAxes,time,pres)
                        app.UIAxes.XLabel.String = 'Time (min)';
                    end
                end
            catch ME
                ME
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Pressure Profile')
            xlabel(app.UIAxes, 'Time (min)')
            ylabel(app.UIAxes, 'Pressure (Torr)')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PrevPres(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end